classdef ImprintSchedule_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Selecttest1folderButton         matlab.ui.control.Button
        Selecttest2folderButton         matlab.ui.control.Button
        Slider1                         matlab.ui.control.Slider
        Slider2                         matlab.ui.control.Slider
        Imagetest1dimLabel              matlab.ui.control.Label
        Imagetest2dimLabel              matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ImprintingPanel                 matlab.ui.container.Panel
        SessionlengthminEditFieldLabel  matlab.ui.control.Label
        SessionlengthminEditField       matlab.ui.control.NumericEditField
        IntersessionspauseminEditFieldLabel  matlab.ui.control.Label
        IntersessionspauseminEditField  matlab.ui.control.NumericEditField
        SessionrepetitionsEditFieldLabel  matlab.ui.control.Label
        SessionrepetitionsEditField     matlab.ui.control.NumericEditField
        RestingtimeminEditFieldLabel    matlab.ui.control.Label
        RestingtimeminEditField         matlab.ui.control.NumericEditField
        NumberofdaysEditFieldLabel      matlab.ui.control.Label
        NumberofdaysEditField           matlab.ui.control.NumericEditField
        TestPanel                       matlab.ui.container.Panel
        SessionlengthminEditField_2Label  matlab.ui.control.Label
        SessionlengthminEditField_2     matlab.ui.control.NumericEditField
        IntersessionspauseminEditField_2Label  matlab.ui.control.Label
        IntersessionspauseminEditField_2  matlab.ui.control.NumericEditField
        SessionrepetitionsEditField_2Label  matlab.ui.control.Label
        SessionrepetitionsEditField_2   matlab.ui.control.NumericEditField
        RestingtimeminEditField_2Label  matlab.ui.control.Label
        RestingtimeminEditField_2       matlab.ui.control.NumericEditField
        NumberofdaysEditField_2Label    matlab.ui.control.Label
        NumberofdaysEditField_2         matlab.ui.control.NumericEditField
        TotaldurationLabel              matlab.ui.control.Label
        EndtimeLabel                    matlab.ui.control.Label
        ScreencheckButton               matlab.ui.control.Button
        StartingtimeddMonyyyyhhmmssLabel  matlab.ui.control.Label
        StartingtimeEditField           matlab.ui.control.EditField
        SaveinfostoEditFieldLabel       matlab.ui.control.Label
        SaveinfostoEditField            matlab.ui.control.EditField
        SkipsynctestCheckBox            matlab.ui.control.CheckBox
        File1Label                      matlab.ui.control.Label
        File2Label                      matlab.ui.control.Label
        MovementparametersPanel         matlab.ui.container.Panel
        ImageframeratefpsEditFieldLabel  matlab.ui.control.Label
        ImageframeratefpsEditField      matlab.ui.control.NumericEditField
        ImageshiftEditFieldLabel        matlab.ui.control.Label
        ImageshiftEditField             matlab.ui.control.NumericEditField
        OscillationamplitudeEditFieldLabel  matlab.ui.control.Label
        OscillationamplitudeEditField   matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        Switch                          matlab.ui.control.Switch
        SelectimprintingfolderButton    matlab.ui.control.Button
        Slider1_2                       matlab.ui.control.Slider
        ImageimprintingdimLabel         matlab.ui.control.Label
        FileimprintingLabel             matlab.ui.control.Label
        Setin4minButton                 matlab.ui.control.Button
        SetnowButton                    matlab.ui.control.Button
        Setin1minButton                 matlab.ui.control.Button
        Setin2minButton                 matlab.ui.control.Button
        Setin3minButton                 matlab.ui.control.Button
        ChecklatenciesButton            matlab.ui.control.Button
        AdjustforPClatenciesCheckBox    matlab.ui.control.CheckBox
        screensDropDownLabel            matlab.ui.control.Label
        screensDropDown                 matlab.ui.control.DropDown
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1_2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgSize % cell{#img}(pix_y,pix_x,col,frames) with images dimentions
        img % cell{#img, frame} with all images
        frameRate % frame/s
        
        tStart % experiment starting time (format dd-Mon-yyyy hh:mm:ss)
        
        tSession %duration (s) of one session (gif presentation) -training
        nSession % # of sessions (per day) -training
        tInterSession % time (s) between sessions -training
        tRest % pause time (s) after end of session (night) -training
        nDays % # of days (cycles of sessions) -training
        tSessionTest %duration (s) of one session (gif presentation) -test
        nSessionTest % # of sessions (per day) -test
        tInterSessionTest % time (s) between sessions -test
        tRestTest % pause time (s) after end of session (night) -test
        nDaysTest % # of days (cycles of sessions) -test
        
        dataTable1 % table with stimuli presentation order -training
        dataTableTest1 % table with stimuli presentation order -test
        dataTable2
        dataTableTest2
        dataTable3
        dataTableTest3
        dataTable4
        dataTableTest4
        shift
        r_img
        r_imgTest
        r_imgTest2
        r_screen
        r_screenTest
        
        freqTraining
        freqTest
        speedImprinting
        speedTest
       
    end
    
    methods (Access = private)
        
        %compute experiment duration and end time (tEnd)
        function tEnd=computeDuration(app)
            totalSecTraining=sym(((app.tSession*double(app.nSession)+app.tInterSession*double(app.nSession-1))*double(app.nDays)+app.tRest*double(app.nDays)));
            totalSecTest=sym(((app.tSessionTest*double(app.nSessionTest)+app.tInterSessionTest*double(app.nSessionTest-1))*double(app.nDaysTest)+app.tRestTest*double(app.nDaysTest-1)));
            totalSec=sym(totalSecTraining+totalSecTest);
            sec=uint32(mod(totalSec, 60));
            totalMin=floor(totalSec/60.);
            min=uint32(mod(totalMin, 60));
            totalHour=floor(totalMin/60.);
            hh=uint32(mod(totalHour,24));
            dd=uint32(floor(totalHour/24));
            app.TotaldurationLabel.Text=(['Tot t: ' num2str(dd) ' d, ' num2str(hh) ' h, ' num2str(min) 'min']);
            if app.StartingtimeEditField.Value <= datetime('now')
                tEnd=datetime('now');
            else
                tEnd=datetime(app.StartingtimeEditField.Value);
            end
            tEnd=tEnd+caldays(dd)+hours(hh)+minutes(min)+seconds(sec);
            app.EndtimeLabel.Text=(['Aproximate end time: ' datestr(tEnd, 'dd-mmm-yyyy HH:MM')]);
        end
        
        %create tables to save stimuli presentation order
        function createTable(app)
            switch app.screensDropDown.Value
                case '2 screens'
                     app.dataTable1=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest1=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                case '4 screens'
                     app.dataTable1=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest1=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                     app.dataTable2=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest2=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                case '6 screens'
                     app.dataTable1=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest1=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                     app.dataTable2=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest2=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                     app.dataTable3=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest3=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                case '8 screens'
                     app.dataTable1=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest1=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                     app.dataTable2=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest2=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                     app.dataTable3=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest3=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                     app.dataTable4=table('Size',[0 6], 'VariableTypes',{'uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'});
                     app.dataTableTest4=table('Size',[0 8], 'VariableTypes',{'uint8','uint8','uint8','uint8','uint8','uint8','datetime', 'double'},'VariableNames',{'Day','Session','Stimulus_statistics', 'Screen_statistics','Stimulus_noise','Screen_noise','Start_time', 'Speed'});
                    
            end
        end
        
        
        function randomSequence(app)
            app.r_img = cell(4,app.nDays);
            app.r_screen = cell(4,app.nDays);
            app.r_imgTest = cell(4,app.nDaysTest);
            app.r_imgTest2 = cell(4,app.nDaysTest);
            app.r_screenTest = cell(4,app.nDaysTest);
            %random stimuli and screen
            for j=1:app.nDays
                switch app.screensDropDown.Value
                    case '2 screens'
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{1,j} = aa(1:app.nSession);
                    case '4 screens'
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{1,j} = aa(1:app.nSession);
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{2,j} = aa(1:app.nSession);
                    case '6 screens'
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{1,j} = aa(1:app.nSession);
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{2,j} = aa(1:app.nSession);
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{3,j} = aa(1:app.nSession);
                    case '8 screens'
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{1,j} = aa(1:app.nSession);
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{2,j} = aa(1:app.nSession);
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{3,j} = aa(1:app.nSession);
                        a=Shuffle(1:app.imgSize{1}(3));
                        aa=repmat(a',100,1);
                        app.r_img{4,j} = aa(1:app.nSession);
                        
                end
               
                %randomize screen output with no more than two consecutive presentations on same side
                if app.nSession == 1
                    switch app.screensDropDown.Value
                        case '2 screens'
                            app.r_screen{1,j}=round(rand)+1;
                        case '4 screens'
                            app.r_screen{1,j}=round(rand)+1;
                            app.r_screen{2,j}=round(rand)+1;
                        case '6 screens'
                            app.r_screen{1,j}=round(rand)+1;
                            app.r_screen{2,j}=round(rand)+1;
                            app.r_screen{3,j}=round(rand)+1;
                        case '8 screens'
                            app.r_screen{1,j}=round(rand)+1;
                            app.r_screen{2,j}=round(rand)+1;
                            app.r_screen{3,j}=round(rand)+1;
                            app.r_screen{4,j}=round(rand)+1;
                    end
                else
                    r_screen{j} = ones(app.nSession, 1);
                    r_screen{j}(1:round(app.nSession/2))=2;
                    switch app.screensDropDown.Value
                        case '2 screens'
                            app.r_screen{1,j}=r_screen{j}(randperm(length(r_screen{j})));
                        case '4 screens'
                            app.r_screen{1,j}=r_screen{j}(randperm(length(r_screen{j})));
                            app.r_screen{2,j}=r_screen{j}(randperm(length(r_screen{j})));
                        case '6 screens'
                            app.r_screen{1,j}=r_screen{j}(randperm(length(r_screen{j})));
                            app.r_screen{2,j}=r_screen{j}(randperm(length(r_screen{j})));
                            app.r_screen{3,j}=r_screen{j}(randperm(length(r_screen{j})));
                        case '8 screens'
                            app.r_screen{1,j}=r_screen{j}(randperm(length(r_screen{j})));
                            app.r_screen{2,j}=r_screen{j}(randperm(length(r_screen{j})));
                            app.r_screen{3,j}=r_screen{j}(randperm(length(r_screen{j})));
                            app.r_screen{4,j}=r_screen{j}(randperm(length(r_screen{j})));
                    end
                end
                
                switch app.screensDropDown.Value
                    case '2 screens'
                        screens=1;
                    case '4 screens'
                        screens=2;
                    case '6 screens'
                        screens=3;
                    case '8 screens'
                        screens=4;
                end

                for z=1:screens
                    if app.nSession>2
                        for i=3:app.nSession
                            if app.r_screen{z,j}(i)==app.r_screen{z,j}(i-1) && app.r_screen{z,j}(i)==app.r_screen{z,j}(i-2)
                                if app.r_screen{z,j}(i)==1
                                    app.r_screen{z,j}(i)=2;
                                else
                                    app.r_screen{z,j}(i)=1;
                                end
                                done=0;
                                if i~= app.nSession
                                    for k=i+1:app.nSession
                                        if app.r_screen{z,j}(i)==1 && app.r_screen{z,j}(k)==1
                                            app.r_screen{z,j}(k)=2;
                                            done=1;
                                            break
                                        elseif app.r_screen{z,j}(i)==2 && app.r_screen{z,j}(k)==2
                                            app.r_screen{z,j}(k)=1;
                                            done=1;
                                            break
                                        else
                                            continue
                                        end
                                    end
                                end
                                if done==0 && i~= 1
                                    for k=1:i-1
                                        if app.r_screen{z,j}(i)==1 && app.r_screen{z,j}(k)==1
                                            app.r_screen{z,j}(k)=2;
                                            done=1;
                                            break
                                        elseif app.r_screen{z,j}(i)==2 && app.r_screen{z,j}(k)==2
                                            app.r_screen{z,j}(k)=1;
                                            done=1;
                                            break
                                        else
                                            continue
                                        end
                                    end
                                end
                                if done==0
                                    disp('Screen randomization not fullfilled!')
                                end
                            end
                        end
                    end
                end
            end
            %randomize stimulus presentation 50/50 test
            for j=1:app.nDaysTest
                switch app.screensDropDown.Value
                    case '2 screens'
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{1,j} = aa(1:app.nSessionTest);
                    case '4 screens'
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{1,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{2,j} = aa(1:app.nSessionTest);
                    case '6 screens'
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{1,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{2,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{3,j} = aa(1:app.nSessionTest);
                    case '8 screens'
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{1,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{2,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{3,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{2}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest{4,j} = aa(1:app.nSessionTest);
                end

                switch app.screensDropDown.Value
                    case '2 screens'
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{1,j} = aa(1:app.nSessionTest);
                    case '4 screens'
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{1,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{2,j} = aa(1:app.nSessionTest);
                    case '6 screens'
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{1,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{2,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{3,j} = aa(1:app.nSessionTest);
                    case '8 screens'
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{1,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{2,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{3,j} = aa(1:app.nSessionTest);
                        a=Shuffle(1:app.imgSize{3}(3));
                        aa=repmat(a',100,1);
                        app.r_imgTest2{4,j} = aa(1:app.nSessionTest);
                end
                
                %randomize screen output with no more than two consecutive presentations on same side
                if app.nSessionTest == 1
                    switch app.screensDropDown.Value
                        case '2 screens'
                            app.r_screenTest{1,j}=round(rand)+1;
                        case '4 screens'
                            app.r_screenTest{1,j}=round(rand)+1;
                            app.r_screenTest{2,j}=round(rand)+1;
                        case '6 screens'
                            app.r_screenTest{1,j}=round(rand)+1;
                            app.r_screenTest{2,j}=round(rand)+1;
                            app.r_screenTest{3,j}=round(rand)+1;
                        case '8 screens'
                            app.r_screenTest{1,j}=round(rand)+1;
                            app.r_screenTest{2,j}=round(rand)+1;
                            app.r_screenTest{3,j}=round(rand)+1;
                            app.r_screenTest{4,j}=round(rand)+1;
                    end
                else
                    r_screenTest{j} = ones(app.nSessionTest, 1);
                    r_screenTest{j}(1:round(app.nSessionTest/2))=2;
                    switch app.screensDropDown.Value
                        case '2 screens'
                            app.r_screenTest{1,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                        case '4 screens'
                            app.r_screenTest{1,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                            app.r_screenTest{2,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                        case '6 screens'
                            app.r_screenTest{1,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                            app.r_screenTest{2,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                            app.r_screenTest{3,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                        case '8 screens'
                            app.r_screenTest{1,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                            app.r_screenTest{2,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                            app.r_screenTest{3,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                            app.r_screenTest{4,j}=r_screenTest{j}(randperm(length(r_screenTest{j})));
                    end
                end
                switch app.screensDropDown.Value
                    case '2 screens'
                        screens=1;
                    case '4 screens'
                        screens=2;
                    case '6 screens'
                        screens=3;
                    case '8 screens'
                        screens=4;
                end

                for z=1:screens
                    if app.nSessionTest>2
                        for i=3:app.nSessionTest
                            if app.r_screenTest{z,j}(i)==app.r_screenTest{z,j}(i-1) && app.r_screenTest{z,j}(i)==app.r_screenTest{z,j}(i-2)
                                if app.r_screenTest{z,j}(i)==1
                                    app.r_screenTest{z,j}(i)=2;
                                else
                                    app.r_screenTest{z,j}(i)=1;
                                end
                                done=0;
                                if i~= app.nSessionTest
                                    for k=i+1:app.nSessionTest
                                        if app.r_screenTest{z,j}(i)==1 && app.r_screenTest{z,j}(k)==1
                                            app.r_screenTest{z,j}(k)=2;
                                            done=1;
                                            break
                                        elseif app.r_screenTest{z,j}(i)==2 && app.r_screenTest{z,j}(k)==2
                                            app.r_screenTest{z,j}(k)=1;
                                            done=1;
                                            break
                                        else
                                            continue
                                        end
                                    end
                                end
                                if done==0 && i~= 1
                                    for k=1:i-1
                                        if app.r_screenTest{z,j}(i)==1 && app.r_screenTest{z,j}(k)==1
                                            app.r_screenTest{z,j}(k)=2;
                                            done=1;
                                            break
                                        elseif app.r_screenTest{z,j}(i)==2 && app.r_screenTest{z,j}(k)==2
                                            app.r_screenTest{z,j}(k)=1;
                                            done=1;
                                            break
                                        else
                                            continue
                                        end
                                    end
                                end
                                if done==0
                                    disp('Screen randomization not fullfilled!')
                                end
                            end
                        end
                    end
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.frameRate=app.ImageframeratefpsEditField.Value;
            app.tSession=app.SessionlengthminEditField.Value*60;
            app.nSession=app.SessionrepetitionsEditField.Value;
            app.tInterSession=app.IntersessionspauseminEditField.Value*60;
            app.tRest=app.RestingtimeminEditField.Value*60;
            app.nDays=app.NumberofdaysEditField.Value;
            app.tSessionTest=app.SessionlengthminEditField_2.Value*60;
            app.nSessionTest=app.SessionrepetitionsEditField_2.Value;
            app.tInterSessionTest=app.IntersessionspauseminEditField_2.Value*60;
            app.tRestTest=app.RestingtimeminEditField_2.Value*60;
            app.nDaysTest=app.NumberofdaysEditField_2.Value;
            app.tStart=datevec(app.StartingtimeEditField.Value);
            app.StartingtimeEditField.Value = datestr(datetime('now'), 'dd-mmm-yyyy HH:MM');
            computeDuration(app);
            createTable(app);
            s=get(0,'ScreenSize');
            app.shift=s(4)/6;
            app.ImageshiftEditField.Value=app.shift;
            app.freqTraining = app.FrequencyEditField.Value;
            app.freqTest = app.FrequencyEditField.Value;
        end

        % Button pushed function: Selecttest1folderButton
        function Selecttest1folderButtonPushed(app, event)
            clc;
            selpath = uigetdir;
            S = dir(fullfile(selpath, '*.png'));
            app.File1Label.Text=S(1).name;
            if numel(S)==0
                disp('No images found!')
            else
                for n = 1:numel(S)
                    F = fullfile(selpath,S(n).name);
                    app.img{3,n} = imread(F);
%                     app.img{3,n} = mat2gray(imread(F));
%                     %convert to red
%                     for i=1:size(app.img{3,n},1)
%                         for j=1:size(app.img{3,n},2)
%                             if app.img{3,n}(i,j,1)~=0||app.img{3,n}(i,j,2)~=0||app.img{3,n}(i,j,3)~=0
%                                 app.img{3,n}(i,j,1)=1;
%                                 app.img{3,n}(i,j,2)=0;
%                                 app.img{3,n}(i,j,3)=0;
%                             end
%                         end
%                     end
                    app.imgSize{3}=size(app.img{3,n});
                end
                app.imgSize{3}(3)=numel(S);
                app.UIAxes1.XLim=[0 app.imgSize{3}(2)];
                app.UIAxes1.YLim=[0 app.imgSize{3}(1)];
                app.Imagetest1dimLabel.Text=['Image random dim: ' num2str(app.imgSize{3}(1)) 'X' num2str(app.imgSize{3}(2))];
                app.Slider1.Limits=[1 app.imgSize{3}(3)];
                %visualize first frame
                imagesc(app.UIAxes1, app.img{3,1})
                app.Selecttest1folderButton.BackgroundColor=[0.25,0.92,0.83];
            end
            figure(app.UIFigure)
        end

        % Button pushed function: Selecttest2folderButton
        function Selecttest2folderButtonPushed(app, event)
            clc;
            selpath = uigetdir;
            S = dir(fullfile(selpath, '*.png'));
            app.File2Label.Text=S(1).name;
            if numel(S)==0
                disp('No images found!')
            else
                for n = 1:numel(S)
                    F = fullfile(selpath,S(n).name);
                    app.img{2,n} = imread(F);
%                     app.img{2,n} = mat2gray(imread(F));
%                     %convert to red
%                     for i=1:size(app.img{2,n},1)
%                         for j=1:size(app.img{2,n},2)
%                             if app.img{2,n}(i,j,1)~=0||app.img{2,n}(i,j,2)~=0||app.img{2,n}(i,j,3)~=0
%                                 app.img{2,n}(i,j,1)=1;
%                                 app.img{2,n}(i,j,2)=0;
%                                 app.img{2,n}(i,j,3)=0;
%                             end
%                         end
%                     end
                    app.imgSize{2}=size(app.img{2,n});
                end
                app.imgSize{2}(3)=numel(S);
                app.UIAxes1.XLim=[0 app.imgSize{2}(2)];
                app.UIAxes1.YLim=[0 app.imgSize{2}(1)];
                app.Imagetest2dimLabel.Text=['Image test dim: ' num2str(app.imgSize{2}(1)) 'X' num2str(app.imgSize{2}(2))];
                app.Slider2.Limits=[1 app.imgSize{2}(3)];
                %visualize first frame
                imagesc(app.UIAxes2, app.img{2,1})
                app.Selecttest2folderButton.BackgroundColor=[0.25,0.92,0.83];
            end
            figure(app.UIFigure)
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            n = round(event.Value);
            if n==0
                n=1;
            end
            imagesc(app.UIAxes2, app.img{2,n})
        end

        % Value changing function: Slider1
        function Slider1ValueChanging(app, event)
            n = round(event.Value);
            if n==0
                n=1;
            end
            imagesc(app.UIAxes1, app.img{3,n})
        end

        % Value changed function: ImageframeratefpsEditField
        function ImageframeratefpsEditFieldValueChanged(app, event)
            app.frameRate = app.ImageframeratefpsEditField.Value;
        end

        % Value changed function: SessionlengthminEditField
        function SessionlengthminEditFieldValueChanged(app, event)
            app.tSession = app.SessionlengthminEditField.Value*60;
            computeDuration(app);
        end

        % Value changed function: IntersessionspauseminEditField
        function IntersessionspauseminEditFieldValueChanged(app, event)
            app.tInterSession = app.IntersessionspauseminEditField.Value*60;
            computeDuration(app);
        end

        % Value changed function: SessionrepetitionsEditField
        function SessionrepetitionsEditFieldValueChanged(app, event)
            app.nSession = uint8(app.SessionrepetitionsEditField.Value);
            computeDuration(app);
        end

        % Value changed function: RestingtimeminEditField
        function RestingtimeminEditFieldValueChanged(app, event)
            app.tRest = app.RestingtimeminEditField.Value*60;
            computeDuration(app);
        end

        % Value changed function: NumberofdaysEditField
        function NumberofdaysEditFieldValueChanged(app, event)
            app.nDays = uint8(app.NumberofdaysEditField.Value);
            computeDuration(app);
            if app.nDays==0
                app.SessionlengthminEditField.Enable='off';
                app.IntersessionspauseminEditField.Enable='off';
                app.SessionrepetitionsEditField.Enable='off';
                app.RestingtimeminEditField.Enable='off';
            else
                app.SessionlengthminEditField.Enable='on';
                app.IntersessionspauseminEditField.Enable='on';
                app.SessionrepetitionsEditField.Enable='on';
                app.RestingtimeminEditField.Enable='on';
            end
        end

        % Value changed function: SessionlengthminEditField_2
        function SessionlengthminEditField_2ValueChanged(app, event)
            app.tSessionTest = app.SessionlengthminEditField_2.Value*60;
            computeDuration(app);
        end

        % Value changed function: IntersessionspauseminEditField_2
        function IntersessionspauseminEditField_2ValueChanged(app, event)
            app.tInterSessionTest = app.IntersessionspauseminEditField_2.Value*60;
            computeDuration(app);
        end

        % Value changed function: SessionrepetitionsEditField_2
        function SessionrepetitionsEditField_2ValueChanged(app, event)
            app.nSessionTest = uint8(app.SessionrepetitionsEditField_2.Value);
            computeDuration(app);
        end

        % Value changed function: RestingtimeminEditField_2
        function RestingtimeminEditField_2ValueChanged(app, event)
            app.tRestTest = app.RestingtimeminEditField_2.Value*60;
            computeDuration(app);
        end

        % Value changed function: NumberofdaysEditField_2
        function NumberofdaysEditField_2ValueChanged(app, event)
            app.nDaysTest = uint8(app.NumberofdaysEditField_2.Value);
            computeDuration(app);
            if app.nDaysTest==0
                app.SessionlengthminEditField_2.Enable='off';
                app.IntersessionspauseminEditField_2.Enable='off';
                app.SessionrepetitionsEditField_2.Enable='off';
                app.RestingtimeminEditField_2.Enable='off';
            else
                app.SessionlengthminEditField_2.Enable='on';
                app.IntersessionspauseminEditField_2.Enable='on';
                app.SessionrepetitionsEditField_2.Enable='on';
                app.RestingtimeminEditField_2.Enable='on';
            end
        end

        % Value changed function: StartingtimeEditField
        function StartingtimeEditFieldValueChanged(app, event)
            app.tStart = datevec(app.StartingtimeEditField.Value);
        end

        % Button pushed function: ScreencheckButton
        function ScreencheckButtonPushed(app, event)
            clc;
            PsychDefaultSetup(2);
            if app.SkipsynctestCheckBox.Value==1
                disp('Synchronization tests skipped!')
                Screen('Preference', 'SuppressAllWarnings');
                Screen('Preference', 'SkipSyncTests', 1); 
            end
            screens=2;
            if app.screensButton.Value==0
                screens=8;
            end
            for i=1:screens
                % Define black and white (white will be 1 and black 0). This is because
                % in general luminace values are defined between 0 and 1 with 255 steps in
                % between. All values in Psychtoolbox are defined between 0 and 1
                white(i) = WhiteIndex(i);    
                black(i) = BlackIndex(i);
                % Open an on screen window using PsychImaging and color it black.
                [window(i), windowRect(i,:,:,:,:)] = PsychImaging('OpenWindow', i, black(i));
                HideCursor;
                % Get the size of the on screen window in pixels, these are the last two numbers in "windowRect"
                [screenXpixels(i), screenYpixels(i)] = Screen('WindowSize', window(i));
                % Get the centre coordinate of the window in pixels.
                [xCenter(i), yCenter(i)] = RectCenter(windowRect(i,:,:,:,:));
                % Query the inter-frame-interval. This refers to the minimum possible time between drawing to the screen
                refreshTime(i) = Screen('GetFlipInterval', window(i));
                % Refresh rate of our screen. The relationship between the two is:
                % refreshTime = 1 / refreshRate
                refreshRate(i) = FrameRate(window(i));
                % Query the frame duration
                numSecs = 1/app.frameRate;
                waitFrames(i) = round(numSecs / refreshTime(i));
                % Queries the display size in mm as reported by the operating system. Note that there are some complexities here. See Screen DisplaySize? for
                % information. So always measure your screen size directly.
                [width_mm_screen(i), height_mm_screen(i)] = Screen('DisplaySize', i); 
                % Set up alpha-blending for smooth (anti-aliased) lines
                Screen('BlendFunction', window(i), 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');
                % Retreive the maximum priority number and set max priority. This means PTB will take processing priority over other system and applicaiton processes.
                topPriorityLevel = MaxPriority(window(i));
                Priority(topPriorityLevel);
                
                % Draw text in the upper portion of the screen with the default font in red
                Screen('TextSize', window(i), 120);
                DrawFormattedText(window(i), ['Screen #' num2str(i)], 'center',screenYpixels(i) * 0.25, [1 0 0]);
                Screen('Flip', window(i));
            end

            KbStrokeWait;
            
            % Make a base Rect of 200 by 200 pixels
            baseRect = [0 0 300 300];
            % Set the color of the rect to red
            rectColor = [1 0 0];
            
            % Our square will oscilate with a sine wave function to the left and right of the screen. These are the parameters for the sine wave
            amplitude = screenXpixels(1) * app.OscillationamplitudeEditField.Value; % horizontal oscillation distance
            if app.screensButton.Value==0
                
            end
            angFreq = 2 * pi * app.frequency;
            startPhase = 0;
            time = 0;
            
            % Sync us and get a time stamp
            vbl = Screen('Flip', window(1));
            for j=2:screens
                Screen('Flip', window(j));
            end
            
            % Maximum priority level
            topPriorityLevel = MaxPriority(window(1));
            Priority(topPriorityLevel);
            
            % Loop the animation until a key is pressed
            while ~KbCheck
            
                % Position of the square on this frame
                xpos = amplitude * sin(angFreq * time + startPhase);
            
                % Add this position to the screen center coordinate. This is the point
                % we want our square to oscillate around
                squareXpos = xCenter(1) + xpos;
            
                % Set y position
                centeredRect = CenterRectOnPointd(baseRect, squareXpos, yCenter(1)+app.ImageshiftEditField.Value);
            
                % Draw the rect to the screen
                for j=1:screens
                    Screen('FillRect', window(j), rectColor, centeredRect);
                end
            
                % Flip to the screen
                vbl  = Screen('Flip', window(1), vbl + (waitFrames(1) - 0.5) * refreshTime(1));
                for j=2:screens
                    Screen('Flip', window(j), vbl + (waitFrames(j) - 0.5) * refreshTime(j));
                end
            
                % Increment the time
                time = time + refreshTime(1);
            
            end
            
            % Clear the screen
            sca;
            if app.SkipsynctestCheckBox.Value==1
                Screen('Preference', 'SkipSyncTests', 0);
                app.SkipsynctestCheckBox.Value=0;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            clc;
            if app.SkipsynctestCheckBox.Value==1
                disp('Synchronization tests skipped!')
                Screen('Preference', 'SuppressAllWarnings');
                Screen('Preference', 'SkipSyncTests', 1); 
            end
            createTable(app);
            randomSequence(app);
            tEnd=computeDuration(app);
           
            clc;
            PsychDefaultSetup(2);
            
            switch app.screensDropDown.Value
                case '2 screens'
                    screens=2;
                case '4 screens'
                    screens=4;
                case '6 screens'
                    screens=6;
                case '8 screens'
                    screens=8;
            end

            for i=1:screens
                % Define black and white (white will be 1 and black 0). This is because
                % in general luminace values are defined between 0 and 1 with 255 steps in
                % between. All values in Psychtoolbox are defined between 0 and 1
                white(i) = WhiteIndex(i);    
                black(i) = BlackIndex(i); 
                % Open an on screen window using PsychImaging and color it black.
                [window(i), windowRect(i,:,:,:,:)] = PsychImaging('OpenWindow', i, black(i));   
                % Get the size of the on screen window in pixels, these are the last two numbers in "windowRect"
                HideCursor;
                [screenXpixels(i), screenYpixels(i)] = Screen('WindowSize', window(i));
                % Get the centre coordinate of the window in pixels.
                [xCenter(i), yCenter(i)] = RectCenter(windowRect(i,:,:,:,:));
                % Query the inter-frame-interval. This refers to the minimum possible time between drawing to the screen
                refreshTime(i) = Screen('GetFlipInterval', window(i));
                % Refresh rate of our screen. The relationship between the two is:
                % refreshTime = 1 / refreshRate
                refreshRate(i) = FrameRate(window(i));
                % Queries the display size in mm as reported by the operating system. Note that there are some complexities here. See Screen DisplaySize? for
                % information. So always measure your screen size directly.
                [width_mm_screen(i), height_mm_screen(i)] = Screen('DisplaySize', i);
                % Set up alpha-blending for smooth (anti-aliased) lines
                Screen('BlendFunction', window(i), 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');
                % Length of time and number of refreshFrames we will use for each drawing test
                numSecs = 1/app.frameRate;
                waitFrames(i) = round(numSecs / refreshTime(i));
                % Retreive the maximum priority number and set max priority. This means PTB will take processing priority over other system and applicaiton processes.
                topPriorityLevel = MaxPriority(window(i));
                Priority(topPriorityLevel);
            end
            
            %wait selected starting time
            disp(['Waiting for ' app.StartingtimeEditField.Value ' to run the experiment...'])
            start=datenum(app.StartingtimeEditField.Value);
            tCurrent=datenum(datetime('now'));
            while start>tCurrent
                tCurrent=datenum(datetime('now'));
            end
            
            disp('TRAINING')
            for day=1:app.nDays
                disp(['Day ' num2str(day)])
                for session=1:app.nSession
                    switch app.screensDropDown.Value
                        case '2 screens'
                            SCR1=app.r_screen{1,day}(session); %screen for statistics texture
                            if SCR1==1
                                SCR1_rnd = 2;
                            else
                                SCR1_rnd = 1;
                            end
                            IMG1=app.r_img{1,day}(session); %image number
                        case '4 screens'
                            SCR1=app.r_screen{1,day}(session); %screen for statistics texture
                            if SCR1==1
                                SCR1_rnd = 2;
                            else
                                SCR1_rnd = 1;
                            end
                            IMG1=app.r_img{1,day}(session); %image number
                            SCR2=app.r_screen{2,day}(session); %screen for statistics texture
                            if SCR2==1
                                SCR2_rnd = 2;
                            else
                                SCR2_rnd = 1;
                            end
                            IMG2=app.r_img{2,day}(session); %image number
                        case '6 screens'
                            SCR1=app.r_screen{1,day}(session); %screen for statistics texture
                            if SCR1==1
                                SCR1_rnd = 2;
                            else
                                SCR1_rnd = 1;
                            end
                            IMG1=app.r_img{1,day}(session); %image number
                            SCR2=app.r_screen{2,day}(session); %screen for statistics texture
                            if SCR2==1
                                SCR2_rnd = 2;
                            else
                                SCR2_rnd = 1;
                            end
                            IMG2=app.r_img{2,day}(session); %image number
                            SCR3=app.r_screen{3,day}(session); %screen for statistics texture
                            if SCR3==1
                                SCR3_rnd = 2;
                            else
                                SCR3_rnd = 1;
                            end
                            IMG3=app.r_img{3,day}(session); %image number
                        case '8 screens'
                            SCR1=app.r_screen{1,day}(session); %screen for statistics texture
                            if SCR1==1
                                SCR1_rnd = 2;
                            else
                                SCR1_rnd = 1;
                            end
                            IMG1=app.r_img{1,day}(session); %image number
                            SCR2=app.r_screen{2,day}(session); %screen for statistics texture
                            if SCR2==1
                                SCR2_rnd = 2;
                            else
                                SCR2_rnd = 1;
                            end
                            IMG2=app.r_img{2,day}(session); %image number
                            SCR3=app.r_screen{3,day}(session); %screen for statistics texture
                            if SCR3==1
                                SCR3_rnd = 2;
                            else
                                SCR3_rnd = 1;
                            end
                            IMG3=app.r_img{3,day}(session); %image number
                            SCR4=app.r_screen{4,day}(session); %screen for statistics texture
                            if SCR4==1
                                SCR4_rnd = 2;
                            else
                                SCR4_rnd = 1;
                            end
                            IMG4=app.r_img{4,day}(session); %image number
                    end

                    vblTime = Screen('Flip', window(1));
                    for j=2:screens
                        Screen('Flip', window(j));
                    end
                    
                    % report stimuli number and screen in a table
                    switch app.screensDropDown.Value
                        case '2 screens'
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR1) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable1=cell2table({day session IMG1 SCR1 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable1.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};                            
                            app.dataTable1=[app.dataTable1; rawTable1];
                        case '4 screens'
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR1) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable1=cell2table({day session IMG1 SCR1 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable1.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};                            
                            app.dataTable1=[app.dataTable1; rawTable1];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR2) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable2=cell2table({day session IMG2 SCR2 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable2.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};
                            app.dataTable2=[app.dataTable2; rawTable2];
                        case '6 screens'
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR1) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable1=cell2table({day session IMG1 SCR1 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable1.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};                            
                            app.dataTable1=[app.dataTable1; rawTable1];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR2) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable2=cell2table({day session IMG2 SCR2 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable2.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};
                            app.dataTable2=[app.dataTable2; rawTable2];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR3) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable3=cell2table({day session IMG3 SCR3 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable3.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};
                            app.dataTable3=[app.dataTable3; rawTable3];
                        case '8 screens'
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR1) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable1=cell2table({day session IMG1 SCR1 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable1.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};                            
                            app.dataTable1=[app.dataTable1; rawTable1];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR2) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable2=cell2table({day session IMG2 SCR2 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable2.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};
                            app.dataTable2=[app.dataTable2; rawTable2];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR3) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable3=cell2table({day session IMG3 SCR3 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable3.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};
                            app.dataTable3=[app.dataTable3; rawTable3];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR4) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable4=cell2table({day session IMG4 SCR4 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable4.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time', 'Speed'};
                            app.dataTable4=[app.dataTable4; rawTable4];
                            
                    end

                    % Maximum priority level
                    topPriorityLevel = MaxPriority(window(SCR1));
                    Priority(topPriorityLevel);
                    % Our square will oscilate with a sine wave function to the left and right of the screen. These are the parameters for the sine wave
                    amplitude = screenXpixels(SCR1) * app.OscillationamplitudeEditField.Value; % horizontal oscillation distance
                    angFreq = 2 * pi * app.freqTraining;
                    startPhase = 0;
                    time = 0;
                    
                    display=1;
                    counterImprinting=0;
                    countFlickTraining = 0;
                    squareXpos1=xCenter(SCR1);
                    squareXpos2=xCenter(SCR1);
                    squareXpos3=xCenter(SCR1);
                    maxAmplTrain=0;
                    t0=vblTime;
                    while vblTime<t0+app.tSession
                        % Position of the square on this frame
                        xpos = amplitude * sin(angFreq * time + startPhase);                               
                        % Add this position to the screen center coordinate. This is the point we want our square to oscillate around
                        switch app.Switch.Value
                            case 'Translation'
                                squareXpos = xCenter(SCR1) + xpos;
                            case 'Flickering'
                                squareXpos = xCenter(SCR1);
                        end
                        squareXpos1=squareXpos2;
                        squareXpos2=squareXpos3;
                        squareXpos3=squareXpos;
                        if ((squareXpos2>squareXpos1) && (squareXpos3<squareXpos2)) || ((squareXpos2<squareXpos1) && (squareXpos3>squareXpos2))
                            if squareXpos3 > maxAmplTrain
                                maxAmplTrain = squareXpos3;
                            end
                            counterImprinting=counterImprinting+1;
                        end
                        % Set y position
                        baseRect = [0,0,app.imgSize{1}(2),app.imgSize{1}(1)];
                        plotWindow = CenterRectOnPointd(baseRect, squareXpos, yCenter(SCR1)+app.ImageshiftEditField.Value);
                        switch app.Switch.Value
                            case 'Translation'
                                % Draw the textures to the screen
                                switch app.screensDropDown.Value
                                    case '2 screens'
                                        imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                        Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                    case '4 screens'
                                        imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                        Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                        imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                        Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);
                                    case '6 screens'
                                        imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                        Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                        imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                        Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                        imageTexture3 = Screen('MakeTexture', window(SCR3+4), app.img{1,IMG3});
                                        Screen('DrawTexture', window(SCR3+4), imageTexture3, [], plotWindow, 0);
                                    case '8 screens'
                                        imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                        Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                        imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                        Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                        imageTexture3 = Screen('MakeTexture', window(SCR3+4), app.img{1,IMG3});
                                        Screen('DrawTexture', window(SCR3+4), imageTexture3, [], plotWindow, 0);
                                        imageTexture4 = Screen('MakeTexture', window(SCR4+6), app.img{1,IMG4});
                                        Screen('DrawTexture', window(SCR4+6), imageTexture4, [], plotWindow, 0);   
                                end

                                % Flip to the screen
                                switch app.screensDropDown.Value
                                    case '2 screens'
                                        vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                        Screen('Flip', window(SCR1_rnd));
                                    case '4 screens'
                                        vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                        Screen('Flip', window(SCR1_rnd));
                                        Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                        Screen('Flip', window(SCR2_rnd+2));
                                    case '6 screens'
                                        vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                        Screen('Flip', window(SCR1_rnd));
                                        Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                        Screen('Flip', window(SCR2_rnd+2));
                                        Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4)); 
                                        Screen('Flip', window(SCR3_rnd+4));
                                    case '8 screens'
                                        vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                        Screen('Flip', window(SCR1_rnd));
                                        Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                        Screen('Flip', window(SCR2_rnd+2));
                                        Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4)); 
                                        Screen('Flip', window(SCR3_rnd+4));
                                        Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6)); 
                                        Screen('Flip', window(SCR4_rnd+6));
                                end

                                % Increment the time
                                switch app.screensDropDown.Value
                                    case '2 screens'
                                        Screen('Close', imageTexture1);
                                    case '4 screens'
                                        Screen('Close', imageTexture1);
                                        Screen('Close', imageTexture2);
                                    case '6 screens'
                                        Screen('Close', imageTexture1);
                                        Screen('Close', imageTexture2);
                                        Screen('Close', imageTexture3);
                                    case '8 screens'
                                        Screen('Close', imageTexture1);
                                        Screen('Close', imageTexture2);
                                        Screen('Close', imageTexture3);
                                        Screen('Close', imageTexture4);
                                end
                                
                            case 'Flickering'
                                if mod(display,2)==0
                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            Screen('FillRect', window(SCR1), black(SCR1));
                                            Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd)); 
                                        case '4 screens'
                                            Screen('FillRect', window(SCR1), black(SCR1));
                                            Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd));
                                            Screen('FillRect', window(SCR2+2), black(SCR2+2));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2));
                                        case '6 screens'
                                            Screen('FillRect', window(SCR1), black(SCR1));
                                            Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd));
                                            Screen('FillRect', window(SCR2+2), black(SCR2+2));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2));
                                            Screen('FillRect', window(SCR3+4), black(SCR3+4));
                                            Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                            Screen('Flip', window(SCR3_rnd+4));
                                        case '8 screens'
                                            Screen('FillRect', window(SCR1), black(SCR1));
                                            Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd));
                                            Screen('FillRect', window(SCR2+2), black(SCR2+2));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2));
                                            Screen('FillRect', window(SCR3+4), black(SCR3+4));
                                            Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                            Screen('Flip', window(SCR3_rnd+4));
                                            Screen('FillRect', window(SCR4+6), black(SCR4+6));
                                            Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6));
                                            Screen('Flip', window(SCR4_rnd+6));
                                    end
                                else
                                    % Draw the textures to the screen
                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                            Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                        case '4 screens'
                                            imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                            Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                            imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                            Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                        case '6 screens'
                                            imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                            Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                            imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                            Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                            imageTexture3 = Screen('MakeTexture', window(SCR3+4), app.img{1,IMG3});
                                            Screen('DrawTexture', window(SCR3+4), imageTexture3, [], plotWindow, 0);
                                        case '8 screens'
                                            imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                            Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                            imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                            Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                            imageTexture3 = Screen('MakeTexture', window(SCR3+4), app.img{1,IMG3});
                                            Screen('DrawTexture', window(SCR3+4), imageTexture3, [], plotWindow, 0);
                                            imageTexture4 = Screen('MakeTexture', window(SCR4+6), app.img{1,IMG4});
                                            Screen('DrawTexture', window(SCR4+6), imageTexture4, [], plotWindow, 0); 
                                    end

                                    % Flip to the screen
                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                            Screen('Flip', window(SCR1_rnd));
                                        case '4 screens'
                                            vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                            Screen('Flip', window(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                            Screen('Flip', window(SCR2_rnd+2));
                                        case '6 screens'
                                            vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                            Screen('Flip', window(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                            Screen('Flip', window(SCR2_rnd+2));
                                            Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4)); 
                                            Screen('Flip', window(SCR3_rnd+4));
                                        case '8 screens'
                                            vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                            Screen('Flip', window(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                            Screen('Flip', window(SCR2_rnd+2));
                                            Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4)); 
                                            Screen('Flip', window(SCR3_rnd+4));
                                            Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6)); 
                                            Screen('Flip', window(SCR4_rnd+6));
                                    end

                                    % Increment the time
                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            Screen('Close', imageTexture1);
                                        case '4 screens'
                                            Screen('Close', imageTexture1);
                                            Screen('Close', imageTexture2);
                                        case '6 screens'
                                            Screen('Close', imageTexture1);
                                            Screen('Close', imageTexture2);
                                            Screen('Close', imageTexture3);
                                        case '8 screens'
                                            Screen('Close', imageTexture1);
                                            Screen('Close', imageTexture2);
                                            Screen('Close', imageTexture3);
                                            Screen('Close', imageTexture4);
                                    end
                                end
                                countFlickTraining = countFlickTraining+1;
                                pause(1/app.freqTraining);

                        end
                        time = time + refreshTime(SCR1);
                        display=display+1;
                    end
                    switch app.screensDropDown.Value
                        case '2 screens'
                            Screen('FillRect', window(SCR1), black(SCR1));
                            Screen('Flip', window(SCR1));
                            Screen('Flip', window(SCR1_rnd));
                        case '4 screens'
                             Screen('FillRect', window(SCR1), black(SCR1));
                            Screen('Flip', window(SCR1));
                            Screen('Flip', window(SCR1_rnd));
                            Screen('FillRect', window(SCR2+2), black(SCR2+2));
                            Screen('Flip', window(SCR2+2));
                            Screen('Flip', window(SCR2_rnd+2));
                        case '6 screens'
                            Screen('FillRect', window(SCR1), black(SCR1));
                            Screen('Flip', window(SCR1));
                            Screen('Flip', window(SCR1_rnd));
                            Screen('FillRect', window(SCR2+2), black(SCR2+2));
                            Screen('Flip', window(SCR2+2));
                            Screen('Flip', window(SCR2_rnd+2));
                            Screen('FillRect', window(SCR3+4), black(SCR3+4));
                            Screen('Flip', window(SCR3+4));
                            Screen('Flip', window(SCR3_rnd+4));
                        case '8 screens'
                            Screen('FillRect', window(SCR1), black(SCR1));
                            Screen('Flip', window(SCR1));
                            Screen('Flip', window(SCR1_rnd));
                            Screen('FillRect', window(SCR2+2), black(SCR2+2));
                            Screen('Flip', window(SCR2+2));
                            Screen('Flip', window(SCR2_rnd+2));
                            Screen('FillRect', window(SCR3+4), black(SCR3+4));
                            Screen('Flip', window(SCR3+4));
                            Screen('Flip', window(SCR3_rnd+4));
                            Screen('FillRect', window(SCR4+6), black(SCR4+6));
                            Screen('Flip', window(SCR4+6));
                            Screen('Flip', window(SCR4_rnd+6));
                    end
                    px2mm_X = width_mm_screen(1)/screenXpixels(1);
                    %disp(['Dimentions: ' num2str(screenYpixels(1)) 'px = ' num2str(height_mm_screen(1)) 'mm'])
                    %disp(['Dimentions: ' num2str(screenXpixels(1)) 'px = ' num2str(width_mm_screen(1)) 'mm'])
                    switch app.Switch.Value
                        case 'Translation'
                            travelledPixelsImprinting = (maxAmplTrain-xCenter(SCR1))*2*counterImprinting-(maxAmplTrain-xCenter(SCR1))+(maxAmplTrain-squareXpos3);
                            %travelledPixelsTest = (maxAmplTest-xCenter(SCR1))*2*counterTest-(maxAmplTest-xCenter(SCR1))+(maxAmplTest-sqpos3);
                            app.speedImprinting{day,session} = travelledPixelsImprinting/app.tSession; %px/s
                            %speedTest = travelledPixelsTest/app.tSessionTest; %px/s
                            disp(['Speed training day ' num2str(day) ' session ' num2str(session) ': ' num2str(app.speedImprinting{day, session}*px2mm_X) ' mm/s'])
                            %disp(['Speed test day ' num2str(day) ' session ' num2str(session) ': ' num2str(speedTest*px2mm_X) ' mm/s'])
                        case 'Flickering'
                            app.speedImprinting{day,session} = countFlickTraining/app.tSession; %flick/s
                            %speedTest = countFlickTest/app.tSessionTest; %flick/s
                            disp(['Speed training day ' num2str(day) ' session ' num2str(session) ': ' num2str(app.speedImprinting{day, session}) ' flick/s'])
                            %disp(['Speed test day ' num2str(day) ' session ' num2str(session) ': ' num2str(speedTest) ' flick/s'])
                    end
                    if session~=app.nSession
                        disp('Pause')
                        pause(app.tInterSession)
                    end
                end
                if day<=app.nDays
                        disp('Pause for night')
                        pause(app.tRest)
                end
            end
            
            disp('TEST')
            for day=1:app.nDaysTest
                disp(['Day ' num2str(day)])
                for session=1:app.nSessionTest
                    switch app.screensDropDown.Value
                        case '2 screens'
                            IMG1_1=app.r_imgTest{1,day}(session); %statistics
                            IMG2_1=app.r_imgTest2{1,day}(session); %noise
                        case '4 screens'
                            IMG1_1=app.r_imgTest{1,day}(session); %statistics
                            IMG2_1=app.r_imgTest2{1,day}(session); %noise
                            IMG1_2=app.r_imgTest{2,day}(session);
                            IMG2_2=app.r_imgTest2{2,day}(session);
                        case '6 screens'
                            IMG1_1=app.r_imgTest{1,day}(session); %statistics
                            IMG2_1=app.r_imgTest2{1,day}(session); %noise
                            IMG1_2=app.r_imgTest{2,day}(session);
                            IMG2_2=app.r_imgTest2{2,day}(session);
                            IMG1_3=app.r_imgTest{3,day}(session);
                            IMG2_3=app.r_imgTest2{3,day}(session);
                        case '8 screens'
                            IMG1_1=app.r_imgTest{1,day}(session); %statistics
                            IMG2_1=app.r_imgTest2{1,day}(session); %noise
                            IMG1_2=app.r_imgTest{2,day}(session);
                            IMG2_2=app.r_imgTest2{2,day}(session);
                            IMG1_3=app.r_imgTest{3,day}(session);
                            IMG2_3=app.r_imgTest2{3,day}(session);
                            IMG1_4=app.r_imgTest{4,day}(session);
                            IMG2_4=app.r_imgTest2{4,day}(session);
                    end

                    switch app.screensDropDown.Value
                        case '2 screens'
                            SCR1=app.r_screenTest{1,day}(session);
                            if SCR1==1
                                SCR1_rnd = 2;
                            else
                                SCR1_rnd = 1;
                            end
                        case '4 screens'
                            SCR1=app.r_screenTest{1,day}(session);
                            if SCR1==1
                                SCR1_rnd = 2;
                            else
                                SCR1_rnd = 1;
                            end
                            SCR2=app.r_screenTest{2,day}(session);
                            if SCR2==1
                                SCR2_rnd = 2;
                            else
                                SCR2_rnd = 1;
                            end
                        case '6 screens'
                            SCR1=app.r_screenTest{1,day}(session);
                            if SCR1==1
                                SCR1_rnd = 2;
                            else
                                SCR1_rnd = 1;
                            end
                            SCR2=app.r_screenTest{2,day}(session);
                            if SCR2==1
                                SCR2_rnd = 2;
                            else
                                SCR2_rnd = 1;
                            end
                            SCR3=app.r_screenTest{3,day}(session);
                            if SCR3==1
                                SCR3_rnd = 2;
                            else
                                SCR3_rnd = 1;
                            end
                        case '8 screens'
                            SCR1=app.r_screenTest{1,day}(session);
                            if SCR1==1
                                SCR1_rnd = 2;
                            else
                                SCR1_rnd = 1;
                            end
                            SCR2=app.r_screenTest{2,day}(session);
                            if SCR2==1
                                SCR2_rnd = 2;
                            else
                                SCR2_rnd = 1;
                            end
                            SCR3=app.r_screenTest{3,day}(session);
                            if SCR3==1
                                SCR3_rnd = 2;
                            else
                                SCR3_rnd = 1;
                            end
                            SCR4=app.r_screenTest{4,day}(session);
                            if SCR4==1
                                SCR4_rnd = 2;
                            else
                                SCR4_rnd = 1;
                            end
                    end

                    vblTime = Screen('Flip', window(1));
                    switch app.screensDropDown.Value
                        case '2 screens'
                            screens=2;
                        case '4 screens'
                            screens=4;
                        case '6 screens'
                            screens=6;
                        case '8 screens'
                            screens=8;
                    end

                    for j=2:screens
                          Screen('Flip', window(j));
                    end
                    
                    % report stimuli number and screen in a table
                    switch app.screensDropDown.Value
                        case '2 screens'
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR1) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable1=cell2table({day session IMG1_1 SCR1 IMG2_1 SCR1_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable1.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest1=[app.dataTableTest1; rawTable1];
                        case '4 screens'
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR1) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable1=cell2table({day session IMG1_1 SCR1 IMG2_1 SCR1_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable1.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest1=[app.dataTableTest1; rawTable1];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR2) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable2=cell2table({day session IMG1_2 SCR2 IMG2_2 SCR2_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable2.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest2=[app.dataTableTest2; rawTable2];
                        case '6 screens'
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR1) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable1=cell2table({day session IMG1_1 SCR1 IMG2_1 SCR1_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable1.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest1=[app.dataTableTest1; rawTable1];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR2) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable2=cell2table({day session IMG1_2 SCR2 IMG2_2 SCR2_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable2.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest2=[app.dataTableTest2; rawTable2];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR3) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable3=cell2table({day session IMG1_3 SCR3 IMG2_3 SCR3_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable3.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest3=[app.dataTableTest3; rawTable3];
                        case '8 screens'
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR1) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable1=cell2table({day session IMG1_1 SCR1 IMG2_1 SCR1_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable1.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest1=[app.dataTableTest1; rawTable1];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR2) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable2=cell2table({day session IMG1_2 SCR2 IMG2_2 SCR2_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable2.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest2=[app.dataTableTest2; rawTable2];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR3) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable3=cell2table({day session IMG1_3 SCR3 IMG2_3 SCR3_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable3.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest3=[app.dataTableTest3; rawTable3];
                            disp(['Session ' num2str(session) ': statistics on screen #' num2str(SCR4) ' starting at time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')]);
                            rawTable4=cell2table({day session IMG1_4 SCR4 IMG2_4 SCR4_rnd datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF') []});
                            rawTable4.Properties.VariableNames = {'Day','Session','Stimulus_statistics', 'Screen_statistics', 'Stimulus_noise', 'Screen_noise', 'Start_time', 'Speed'};                            
                            app.dataTableTest4=[app.dataTableTest4; rawTable4];
                    end
                    
                    % Maximum priority level
                    topPriorityLevel = MaxPriority(window(SCR1));
                    Priority(topPriorityLevel);
                    % Our square will oscilate with a sine wave function to the left and right of the screen. These are the parameters for the sine wave
                    amplitude1 = screenXpixels(SCR1) * app.OscillationamplitudeEditField.Value; % horizontal oscillation distance
                    amplitude2 = screenXpixels(SCR1_rnd) * app.OscillationamplitudeEditField.Value;
                    angFreq = 2 * pi * app.freqTest;
                    startPhase = 0;
                    time = 0;
                    
                    display=1;
                    counterTest=0;
                    countFlickTest = 0;
                    sqpos1=xCenter(SCR1);
                    sqpos2=xCenter(SCR1);
                    sqpos3=xCenter(SCR1);
                    maxAmplTest = 0;
                    t0=vblTime;
                    while vblTime<t0+app.tSessionTest
                        % Position of the square on this frame
                        xpos1 = amplitude1 * sin(angFreq * time + startPhase);
                        xpos2 = -amplitude2 * sin(angFreq * time + startPhase);     
                        % Add this position to the screen center coordinate. This is the point we want our square to oscillate around
                        switch app.Switch.Value
                            case 'Translation'
                                squareXpos1 = xCenter(SCR1) + xpos1;
                                squareXpos2 = xCenter(SCR1_rnd) + xpos2;
                            case 'Flickering'
                                squareXpos1 = xCenter(SCR1);
                                squareXpos2 = xCenter(SCR1_rnd);
                        end
                        sqpos1=sqpos2;
                        sqpos2=sqpos3;
                        sqpos3=squareXpos1;
                        if ((sqpos2>sqpos1) && (sqpos3<sqpos2)) || ((sqpos2<sqpos1) && (sqpos3>sqpos2))
                            if sqpos3 > maxAmplTest
                                maxAmplTest = sqpos3;
                            end
                            counterTest=counterTest+1;
                        end
                        % Set y position
                        baseRect1 = [0,0,app.imgSize{2}(2),app.imgSize{2}(1)];
                        baseRect2 = [0,0,app.imgSize{3}(2),app.imgSize{3}(1)];
                        plotWindow1 = CenterRectOnPointd(baseRect1, squareXpos1, yCenter(SCR1)+app.ImageshiftEditField.Value);
                        plotWindow2 = CenterRectOnPointd(baseRect2, squareXpos2, yCenter(SCR1_rnd)+app.ImageshiftEditField.Value);
                        switch app.Switch.Value
                            case 'Translation'
                                % Draw the textures to the screen
                                switch app.screensDropDown.Value
                                    case '2 screens'
                                        imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                        imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                        Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                    case '4 screens'
                                        imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                        imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                        Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                        imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                        imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                        Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                    case '6 screens'
                                        imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                        imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                        Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                        imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                        imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                        Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                        imageTexture5 = Screen('MakeTexture', window(SCR3+4), app.img{2,IMG1_3});
                                        imageTexture6 = Screen('MakeTexture', window(SCR3_rnd+4), app.img{3,IMG2_3});
                                        Screen('DrawTexture', window(SCR3+4), imageTexture5, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR3_rnd+4), imageTexture6, [], plotWindow2, 0); 
                                    case '8 screens'
                                        imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                        imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                        Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                        imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                        imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                        Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                        imageTexture5 = Screen('MakeTexture', window(SCR3+4), app.img{2,IMG1_3});
                                        imageTexture6 = Screen('MakeTexture', window(SCR3_rnd+4), app.img{3,IMG2_3});
                                        Screen('DrawTexture', window(SCR3+4), imageTexture5, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR3_rnd+4), imageTexture6, [], plotWindow2, 0); 
                                        imageTexture7 = Screen('MakeTexture', window(SCR4+6), app.img{2,IMG1_4});
                                        imageTexture8 = Screen('MakeTexture', window(SCR4_rnd+6), app.img{3,IMG2_4});
                                        Screen('DrawTexture', window(SCR4+6), imageTexture7, [], plotWindow1, 0); 
                                        Screen('DrawTexture', window(SCR4_rnd+6), imageTexture8, [], plotWindow2, 0); 
                                end

                                % Flip to the screen
                                switch app.screensDropDown.Value
                                    case '2 screens'
                                        vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                        Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                    case '4 screens'
                                        vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                        Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                        Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                        Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                    case '6 screens'
                                        vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                        Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                        Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                        Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                        Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                        Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                    case '8 screens'
                                        vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                        Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                        Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                        Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                        Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                        Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                        Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6));
                                        Screen('Flip', window(SCR4_rnd+6), vblTime + (waitFrames(SCR4_rnd+6) - 0.5) * refreshTime(SCR4_rnd+6));
                                end

                                switch app.screensDropDown.Value
                                    case '2 screens'
                                        Screen('Close', imageTexture1);
                                        Screen('Close', imageTexture2);
                                    case '4 screens'
                                        Screen('Close', imageTexture1);
                                        Screen('Close', imageTexture2);
                                        Screen('Close', imageTexture3);
                                        Screen('Close', imageTexture4);
                                    case '6 screens'
                                        Screen('Close', imageTexture1);
                                        Screen('Close', imageTexture2);
                                        Screen('Close', imageTexture3);
                                        Screen('Close', imageTexture4);
                                        Screen('Close', imageTexture5);
                                        Screen('Close', imageTexture6);
                                    case '8 screens'
                                        Screen('Close', imageTexture1);
                                        Screen('Close', imageTexture2);
                                        Screen('Close', imageTexture3);
                                        Screen('Close', imageTexture4);
                                        Screen('Close', imageTexture5);
                                        Screen('Close', imageTexture6);
                                        Screen('Close', imageTexture7);
                                        Screen('Close', imageTexture8);
                                end

                            case 'Flickering'
                                if mod(display,2)==0
                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            Screen('FillRect', window(1), black(1));
                                            Screen('FillRect', window(2), black(2));
                                        case '4 screens'
                                            Screen('FillRect', window(1), black(1));
                                            Screen('FillRect', window(2), black(2));
                                            Screen('FillRect', window(3), black(3));
                                            Screen('FillRect', window(4), black(4));
                                        case '6 screens'
                                            Screen('FillRect', window(1), black(1));
                                            Screen('FillRect', window(2), black(2));
                                            Screen('FillRect', window(3), black(3));
                                            Screen('FillRect', window(4), black(4));
                                            Screen('FillRect', window(5), black(5));
                                            Screen('FillRect', window(6), black(6));
                                        case '8 screens'
                                            Screen('FillRect', window(1), black(1));
                                            Screen('FillRect', window(2), black(2));
                                            Screen('FillRect', window(3), black(3));
                                            Screen('FillRect', window(4), black(4));
                                            Screen('FillRect', window(5), black(5));
                                            Screen('FillRect', window(6), black(6));
                                            Screen('FillRect', window(7), black(7));
                                            Screen('FillRect', window(8), black(8));
                                    end

                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                        case '4 screens'
                                            Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                        case '6 screens'
                                            Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                            Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                            Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                        case '8 screens'
                                            Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                            Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                            Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                            Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6));
                                            Screen('Flip', window(SCR4_rnd+6), vblTime + (waitFrames(SCR4_rnd+6) - 0.5) * refreshTime(SCR4_rnd+6));
                                    end
                                else
                                    % Draw the textures to the screen
                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                            imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                            Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                        case '4 screens'
                                            imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                            imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                            Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                            imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                            imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                            Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                        case '6 screens'
                                            imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                            imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                            Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                            imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                            imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                            Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                            imageTexture5 = Screen('MakeTexture', window(SCR3+4), app.img{2,IMG1_3});
                                            imageTexture6 = Screen('MakeTexture', window(SCR3_rnd+4), app.img{3,IMG2_3});
                                            Screen('DrawTexture', window(SCR3+4), imageTexture5, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR3_rnd+4), imageTexture6, [], plotWindow2, 0); 
                                        case '8 screens'
                                            imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                            imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                            Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                            imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                            imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                            Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                            imageTexture5 = Screen('MakeTexture', window(SCR3+4), app.img{2,IMG1_3});
                                            imageTexture6 = Screen('MakeTexture', window(SCR3_rnd+4), app.img{3,IMG2_3});
                                            Screen('DrawTexture', window(SCR3+4), imageTexture5, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR3_rnd+4), imageTexture6, [], plotWindow2, 0); 
                                            imageTexture7 = Screen('MakeTexture', window(SCR4+6), app.img{2,IMG1_4});
                                            imageTexture8 = Screen('MakeTexture', window(SCR4_rnd+6), app.img{3,IMG2_4});
                                            Screen('DrawTexture', window(SCR4+6), imageTexture7, [], plotWindow1, 0); 
                                            Screen('DrawTexture', window(SCR4_rnd+6), imageTexture8, [], plotWindow2, 0); 
                                    end

                                    % Flip to the screen
                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                        case '4 screens'
                                            vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                        case '6 screens'
                                            vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                            Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                            Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                        case '8 screens'
                                            vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                            Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                            Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                            Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                            Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                            Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                            Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6));
                                            Screen('Flip', window(SCR4_rnd+6), vblTime + (waitFrames(SCR4_rnd+6) - 0.5) * refreshTime(SCR4_rnd+6));
                                    end

                                    switch app.screensDropDown.Value
                                        case '2 screens'
                                            Screen('Close', imageTexture1);
                                            Screen('Close', imageTexture2);
                                        case '4 screens'
                                            Screen('Close', imageTexture1);
                                            Screen('Close', imageTexture2);
                                            Screen('Close', imageTexture3);
                                            Screen('Close', imageTexture4);
                                        case '6 screens'
                                            Screen('Close', imageTexture1);
                                            Screen('Close', imageTexture2);
                                            Screen('Close', imageTexture3);
                                            Screen('Close', imageTexture4);
                                            Screen('Close', imageTexture5);
                                            Screen('Close', imageTexture6);
                                        case '8 screens'
                                            Screen('Close', imageTexture1);
                                            Screen('Close', imageTexture2);
                                            Screen('Close', imageTexture3);
                                            Screen('Close', imageTexture4);
                                            Screen('Close', imageTexture5);
                                            Screen('Close', imageTexture6);
                                            Screen('Close', imageTexture7);
                                            Screen('Close', imageTexture8);
                                    end
                                end
                                countFlickTest = countFlickTest+1;
                                pause(1/app.freqTest);
                        end
                        % Increment the time
                        time = time + refreshTime(SCR1);
                        display=display+1;
                    end
                    switch app.screensDropDown.Value
                        case '2 screens'
                            Screen('FillRect', window(1), black(1));
                            Screen('FillRect', window(2), black(2));
                        case '4 screens'
                            Screen('FillRect', window(1), black(1));
                            Screen('FillRect', window(2), black(2));
                            Screen('FillRect', window(3), black(3));
                            Screen('FillRect', window(4), black(4));
                        case '6 screens'
                            Screen('FillRect', window(1), black(1));
                            Screen('FillRect', window(2), black(2));
                            Screen('FillRect', window(3), black(3));
                            Screen('FillRect', window(4), black(4));
                            Screen('FillRect', window(5), black(5));
                            Screen('FillRect', window(6), black(6));
                        case '8 screens'
                            Screen('FillRect', window(1), black(1));
                            Screen('FillRect', window(2), black(2));
                            Screen('FillRect', window(3), black(3));
                            Screen('FillRect', window(4), black(4));
                            Screen('FillRect', window(5), black(5));
                            Screen('FillRect', window(6), black(6));
                            Screen('FillRect', window(7), black(7));
                            Screen('FillRect', window(8), black(8));
                    end

                    switch app.screensDropDown.Value
                        case '2 screens'
                            Screen('Flip', window(1));
                            Screen('Flip', window(2));
                        case '4 screens'
                            Screen('Flip', window(1));
                            Screen('Flip', window(2)); 
                            Screen('Flip', window(3));
                            Screen('Flip', window(4));
                        case '6 screens'
                            Screen('Flip', window(1));
                            Screen('Flip', window(2)); 
                            Screen('Flip', window(3));
                            Screen('Flip', window(4));
                            Screen('Flip', window(5));
                            Screen('Flip', window(6));
                        case '8 screens'
                            Screen('Flip', window(1));
                            Screen('Flip', window(2)); 
                            Screen('Flip', window(3));
                            Screen('Flip', window(4));
                            Screen('Flip', window(5));
                            Screen('Flip', window(6));
                            Screen('Flip', window(7));
                            Screen('Flip', window(8));
                    end
                    px2mm_X = width_mm_screen(1)/screenXpixels(1);
                    %disp(['Dimentions: ' num2str(screenYpixels(1)) 'px = ' num2str(height_mm_screen(1)) 'mm'])
                    %disp(['Dimentions: ' num2str(screenXpixels(1)) 'px = ' num2str(width_mm_screen(1)) 'mm'])
                    switch app.Switch.Value
                        case 'Translation'
                            %travelledPixelsImprinting = (maxAmplTrain-xCenter(SCR1))*2*counterImprinting-(maxAmplTrain-xCenter(SCR1))+(maxAmplTrain-squareXpos3);
                            travelledPixelsTest = (maxAmplTest-xCenter(SCR1))*2*counterTest-(maxAmplTest-xCenter(SCR1))+(maxAmplTest-sqpos3);
                            %speedImprinting = travelledPixelsImprinting/app.tSession; %px/s
                            app.speedTest{day, session} = travelledPixelsTest/app.tSessionTest; %px/s
                            %disp(['Speed training day ' num2str(day) ' session ' num2str(session) ': ' num2str(speedImprinting*px2mm_X) ' mm/s'])
                            disp(['Speed test day ' num2str(day) ' session ' num2str(session) ': ' num2str(app.speedTest{day, session}*px2mm_X) ' mm/s'])
                        case 'Flickering'
                            %speedImprinting = countFlickTraining/app.tSession; %flick/s
                            app.speedTest{day, session} = countFlickTest/app.tSessionTest; %flick/s
                            %disp(['Speed training day ' num2str(day) ' session ' num2str(session) ': ' num2str(speedImprinting) ' flick/s'])
                            disp(['Speed test day ' num2str(day) ' session ' num2str(session) ': ' num2str(app.speedTest{day, session}) ' flick/s'])
                    end
                    if session~=app.nSessionTest
                        disp('Pause')
                        pause(app.tInterSessionTest)
                    end
                end
                if day<app.nDaysTest
                        disp('Pause for night')
                        pause(app.tRestTest)
                end
            end
            
            sca;
            px2mm_X = width_mm_screen(1)/screenXpixels(1);
            new_col = [];
            new_col_test = [];
            for d =1:app.nDays
                for s = 1: app.nSession
                    switch app.Switch.Value
                        case 'Translation'
                           new_col = [new_col; app.speedImprinting{d,s}*px2mm_X];
                        case 'Flickering'
                           new_col = [new_col; app.speedImprinting{d,s}]; 
                    end
                end
            end
            for d =1:app.nDaysTest
                for s = 1: app.nSessionTest
                    switch app.Switch.Value
                        case 'Translation'
                           new_col_test = [new_col_test; app.speedTest{d,s}*px2mm_X];
                        case 'Flickering'
                           new_col_test = [new_col_test; app.speedTest{d,s}]; 
                    end
                end
            end
            switch app.screensDropDown.Value
                case '2 screens'
                    app.dataTable1.Speed = new_col;
                    app.dataTableTest1.Speed = new_col_test;
                    app.dataTable2.Speed = new_col;
                    writetable(app.dataTable1,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest1,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',2)
                case '4 screens'
                    app.dataTable1.Speed = new_col;
                    app.dataTableTest1.Speed = new_col_test;
                    app.dataTable2.Speed = new_col;
                    app.dataTableTest2.Speed = new_col_test;
                    writetable(app.dataTable1,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest1,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',2)
                    writetable(app.dataTable2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',2)
                case '6 screens'
                    app.dataTable1.Speed = new_col;
                    app.dataTableTest1.Speed = new_col_test;
                    app.dataTable2.Speed = new_col;
                    app.dataTableTest2.Speed = new_col_test;
                    app.dataTable3.Speed = new_col;
                    app.dataTableTest3.Speed = new_col_test;
                    writetable(app.dataTable1,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest1,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',2)
                    writetable(app.dataTable2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',2)
                    writetable(app.dataTable3,[app.SaveinfostoEditField.Value '_3.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest3,[app.SaveinfostoEditField.Value '_3.xlsx'],'Sheet',2)
                case '8 screens'
                    app.dataTable1.Speed = new_col;
                    app.dataTableTest1.Speed = new_col_test;
                    app.dataTable2.Speed = new_col;
                    app.dataTableTest2.Speed = new_col_test;
                    app.dataTable3.Speed = new_col;
                    app.dataTableTest3.Speed = new_col_test;
                    app.dataTable4.Speed = new_col;
                    app.dataTableTest4.Speed = new_col_test;
                    writetable(app.dataTable1,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest1,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',2)
                    writetable(app.dataTable2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',2)
                    writetable(app.dataTable3,[app.SaveinfostoEditField.Value '_3.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest3,[app.SaveinfostoEditField.Value '_3.xlsx'],'Sheet',2)
                    writetable(app.dataTable4,[app.SaveinfostoEditField.Value '_4.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest4,[app.SaveinfostoEditField.Value '_4.xlsx'],'Sheet',2)
            end
        end

        % Callback function
        function ChecksequenceButtonPushed(app, event)
            createTable(app)
            randomSequence(app)
            %training
            for day=1:app.nDays
                for session=1:app.nSession    
                    rawTable=cell2table({day session app.r_img{day}(session) app.r_screen{day}(session) datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')});
                    rawTable.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time'};                            
                    app.dataTable=[app.dataTable; rawTable];
                end
            end
            %test
            for day=1:app.nDaysTest
                for session=1:app.nSessionTest
                    rawTable=cell2table({day session app.r_imgTest{day}(session) 1 datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF')});
                    rawTable.Properties.VariableNames = {'Day','Session','Stimulus', 'Screen', 'Start_time'};
                    app.dataTableTest=[app.dataTableTest; rawTable];
                end
            end
            
            switch app.screensDropDown.Value
                case '2 screens'
                    writetable(app.dataTable,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',2)
                case '4 screens'
                    writetable(app.dataTable,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',2)
                    writetable(app.dataTable2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',2)
                case '6 screens'
                    writetable(app.dataTable,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',2)
                    writetable(app.dataTable2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',2)
                    writetable(app.dataTable3,[app.SaveinfostoEditField.Value '_3.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest3,[app.SaveinfostoEditField.Value '_3.xlsx'],'Sheet',2)
                case '8 screens'
                    writetable(app.dataTable,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest,[app.SaveinfostoEditField.Value '.xlsx'],'Sheet',2)
                    writetable(app.dataTable2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest2,[app.SaveinfostoEditField.Value '_2.xlsx'],'Sheet',2)
                    writetable(app.dataTable3,[app.SaveinfostoEditField.Value '_3.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest3,[app.SaveinfostoEditField.Value '_3.xlsx'],'Sheet',2)
                    writetable(app.dataTable4,[app.SaveinfostoEditField.Value '_4.xlsx'],'Sheet',1)
                    writetable(app.dataTableTest4,[app.SaveinfostoEditField.Value '_4.xlsx'],'Sheet',2)
            end
        end

        % Value changed function: ImageshiftEditField
        function ImageshiftEditFieldValueChanged(app, event)
            app.shift = app.ImageshiftEditField.Value;
        end

        % Button pushed function: SelectimprintingfolderButton
        function SelectimprintingfolderButtonPushed(app, event)
            clc;
            selpath = uigetdir;
            S = dir(fullfile(selpath, '*.png'));
            app.FileimprintingLabel.Text=S(1).name;
            if numel(S)==0
                disp('No images found!')
            else
                for n = 1:numel(S)
                    F = fullfile(selpath,S(n).name);
                    app.img{1,n} = imread(F);
%                     app.img{1,n} = mat2gray(imread(F));
%                     %convert to red
%                     for i=1:size(app.img{1,n},1)
%                         for j=1:size(app.img{1,n},2)
%                             if app.img{1,n}(i,j,1)~=0||app.img{1,n}(i,j,2)~=0||app.img{1,n}(i,j,3)~=0
%                                 app.img{1,n}(i,j,1)=1;
%                                 app.img{1,n}(i,j,2)=0;
%                                 app.img{1,n}(i,j,3)=0;
%                             end
%                         end
%                     end
                    app.imgSize{1}=size(app.img{1,n});
                end
                app.imgSize{1}(3)=numel(S);
                app.UIAxes1_2.XLim=[0 app.imgSize{1}(2)];
                app.UIAxes1_2.YLim=[0 app.imgSize{1}(1)];
                app.ImageimprintingdimLabel.Text=['Image imprinting dim: ' num2str(app.imgSize{1}(1)) 'X' num2str(app.imgSize{1}(2))];
                app.Slider1_2.Limits=[1 app.imgSize{1}(3)];
                %visualize first frame
                imagesc(app.UIAxes1_2, app.img{1,1})
                app.SelectimprintingfolderButton.BackgroundColor=[0.25,0.92,0.83];
            end
            figure(app.UIFigure)
        end

        % Value changing function: Slider1_2
        function Slider1_2ValueChanging(app, event)

            n = round(event.Value);
            if n==0
                n=1;
            end
            imagesc(app.UIAxes1_2, app.img{1,n})
        end

        % Button pushed function: Setin4minButton
        function Setin4minButtonPushed(app, event)
            app.StartingtimeEditField.Value = datestr(datetime('now')+minutes(4), 'dd-mmm-yyyy HH:MM');
            computeDuration(app);
        end

        % Button pushed function: SetnowButton
        function SetnowButtonPushed(app, event)
            app.StartingtimeEditField.Value = datestr(datetime('now'), 'dd-mmm-yyyy HH:MM');
            computeDuration(app);
        end

        % Button pushed function: Setin1minButton
        function Setin1minButtonPushed(app, event)
            app.StartingtimeEditField.Value = datestr(datetime('now')+minutes(1), 'dd-mmm-yyyy HH:MM');
            computeDuration(app);
        end

        % Button pushed function: Setin2minButton
        function Setin2minButtonPushed(app, event)
            app.StartingtimeEditField.Value = datestr(datetime('now')+minutes(2), 'dd-mmm-yyyy HH:MM');
            computeDuration(app);
        end

        % Button pushed function: Setin3minButton
        function Setin3minButtonPushed(app, event)
            app.StartingtimeEditField.Value = datestr(datetime('now')+minutes(3), 'dd-mmm-yyyy HH:MM');
            computeDuration(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Translation'
                    app.OscillationamplitudeEditField.Enable='on';
                    app.FrequencyEditField.Value=0.11;
                    app.freqTraining=0.11;
                    app.freqTest=0.11;
                    app.ChecklatenciesButton.BackgroundColor = [1.00,0.41,0.16];
                    app.AdjustforPClatenciesCheckBox.Value = 0;
                case 'Flickering'
                    app.OscillationamplitudeEditField.Enable='off';
                    app.FrequencyEditField.Value=2;
                    app.freqTraining=2;
                    app.freqTest=2;
                    app.ChecklatenciesButton.BackgroundColor = [1.00,0.41,0.16];
                    app.AdjustforPClatenciesCheckBox.Value = 0;
            end
        end

        % Button pushed function: ChecklatenciesButton
        function ChecklatenciesButtonPushed(app, event)
            clc;
            if app.SkipsynctestCheckBox.Value==1
                disp('Synchronization tests skipped!')
                Screen('Preference', 'SuppressAllWarnings');
                Screen('Preference', 'SkipSyncTests', 1); 
            end
            
            randomSequence(app);
            clc;
            PsychDefaultSetup(2);
            switch app.screensDropDown.Value
                case '2 screens'
                    screens=2;
                case '4 screens'
                    screens=4;
                case '6 screens'
                    screens=6;
                case '8 screens'
                    screens=8;
            end

            for i=1:screens
                % Define black and white (white will be 1 and black 0). This is because
                % in general luminace values are defined between 0 and 1 with 255 steps in
                % between. All values in Psychtoolbox are defined between 0 and 1
                white(i) = WhiteIndex(i);    
                black(i) = BlackIndex(i); 
                % Open an on screen window using PsychImaging and color it black.
                [window(i), windowRect(i,:,:,:,:)] = PsychImaging('OpenWindow', i, black(i));   
                % Get the size of the on screen window in pixels, these are the last two numbers in "windowRect"
                HideCursor;
                [screenXpixels(i), screenYpixels(i)] = Screen('WindowSize', window(i));
                % Get the centre coordinate of the window in pixels.
                [xCenter(i), yCenter(i)] = RectCenter(windowRect(i,:,:,:,:));
                % Query the inter-frame-interval. This refers to the minimum possible time between drawing to the screen
                refreshTime(i) = Screen('GetFlipInterval', window(i));
                % Refresh rate of our screen. The relationship between the two is:
                % refreshTime = 1 / refreshRate
                refreshRate(i) = FrameRate(window(i));
                % Queries the display size in mm as reported by the operating system. Note that there are some complexities here. See Screen DisplaySize? for
                % information. So always measure your screen size directly.
                [width_mm_screen(i), height_mm_screen(i)] = Screen('DisplaySize', i);
                % Set up alpha-blending for smooth (anti-aliased) lines
                Screen('BlendFunction', window(i), 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');
                % Length of time and number of refreshFrames we will use for each drawing test
                numSecs = 1/app.frameRate;
                waitFrames(i) = round(numSecs / refreshTime(i));
                % Retreive the maximum priority number and set max priority. This means PTB will take processing priority over other system and applicaiton processes.
                topPriorityLevel = MaxPriority(window(i));
                Priority(topPriorityLevel);
            end

            disp('TRAINING')
            switch app.screensDropDown.Value
                case '2 screens'
                    SCR1=app.r_screen{1,1}(1); %screen for statistics texture
                    if SCR1==1
                        SCR1_rnd = 2;
                    else
                        SCR1_rnd = 1;
                    end
                    IMG1=app.r_img{1,1}(1); %image number
                case '4 screens'
                    SCR1=app.r_screen{1,1}(1); %screen for statistics texture
                    if SCR1==1
                        SCR1_rnd = 2;
                    else
                        SCR1_rnd = 1;
                    end
                    IMG1=app.r_img{1,1}(1); %image number
                    SCR2=app.r_screen{2,1}(1); %screen for statistics texture
                    if SCR2==1
                        SCR2_rnd = 2;
                    else
                        SCR2_rnd = 1;
                    end
                    IMG2=app.r_img{2,1}(1); %image number
                case '6 screens'
                    SCR1=app.r_screen{1,1}(1); %screen for statistics texture
                    if SCR1==1
                        SCR1_rnd = 2;
                    else
                        SCR1_rnd = 1;
                    end
                    IMG1=app.r_img{1,1}(1); %image number
                    SCR2=app.r_screen{2,1}(1); %screen for statistics texture
                    if SCR2==1
                        SCR2_rnd = 2;
                    else
                        SCR2_rnd = 1;
                    end
                    IMG2=app.r_img{2,1}(1); %image number
                    SCR3=app.r_screen{3,1}(1); %screen for statistics texture
                    if SCR3==1
                        SCR3_rnd = 2;
                    else
                        SCR3_rnd = 1;
                    end
                    IMG3=app.r_img{3,1}(1); %image number
                case '8 screens'
                    SCR1=app.r_screen{1,1}(1); %screen for statistics texture
                    if SCR1==1
                        SCR1_rnd = 2;
                    else
                        SCR1_rnd = 1;
                    end
                    IMG1=app.r_img{1,1}(1); %image number
                    SCR2=app.r_screen{2,1}(1); %screen for statistics texture
                    if SCR2==1
                        SCR2_rnd = 2;
                    else
                        SCR2_rnd = 1;
                    end
                    IMG2=app.r_img{2,1}(1); %image number
                    SCR3=app.r_screen{3,1}(1); %screen for statistics texture
                    if SCR3==1
                        SCR3_rnd = 2;
                    else
                        SCR3_rnd = 1;
                    end
                    IMG3=app.r_img{3,1}(1); %image number
                    SCR4=app.r_screen{4,1}(1); %screen for statistics texture
                    if SCR4==1
                        SCR4_rnd = 2;
                    else
                        SCR4_rnd = 1;
                    end
                    IMG4=app.r_img{4,1}(1); %image number
            end
            
            vblTime = Screen('Flip', window(1));
            for j=2:screens
                Screen('Flip', window(j));
            end

            % Maximum priority level
            topPriorityLevel = MaxPriority(window(SCR1));
            Priority(topPriorityLevel);
            % Our square will oscilate with a sine wave function to the left and right of the screen. These are the parameters for the sine wave
            amplitude = screenXpixels(SCR1) * app.OscillationamplitudeEditField.Value; % horizontal oscillation distance
            angFreq = 2 * pi * app.freqTraining;
            startPhase = 0;
            time = 0;
            
            display=1;
            t0=vblTime;
            counterImprinting=0;
            countFlickTraining = 0;
            squareXpos1=xCenter(SCR1);
            squareXpos2=xCenter(SCR1);
            squareXpos3=xCenter(SCR1);
            maxAmplTrain=0;
            while vblTime<t0+30
                % Position of the square on this frame
                xpos = amplitude * sin(angFreq * time + startPhase);
                % Add this position to the screen center coordinate. This is the point we want our square to oscillate around
                switch app.Switch.Value
                    case 'Translation'
                        squareXpos = xCenter(SCR1) + xpos;
                    case 'Flickering'
                        squareXpos = xCenter(SCR1);
                end
                squareXpos1=squareXpos2;
                squareXpos2=squareXpos3;
                squareXpos3=squareXpos;
                if ((squareXpos2>squareXpos1) && (squareXpos3<squareXpos2)) || ((squareXpos2<squareXpos1) && (squareXpos3>squareXpos2))
                    if squareXpos3 > maxAmplTrain
                        maxAmplTrain = squareXpos3;
                    end
                    counterImprinting=counterImprinting+1;
                end
                % Set y position
                baseRect = [0,0,app.imgSize{1}(2),app.imgSize{1}(1)];
                plotWindow = CenterRectOnPointd(baseRect, squareXpos, yCenter(SCR1)+app.ImageshiftEditField.Value);
                switch app.Switch.Value
                    case 'Translation'
                        % Draw the textures to the screen
                        switch app.screensDropDown.Value
                            case '2 screens'
                                imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                            case '4 screens'
                                imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                            case '6 screens'
                                imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                imageTexture3 = Screen('MakeTexture', window(SCR3+4), app.img{1,IMG3});
                                Screen('DrawTexture', window(SCR3+4), imageTexture3, [], plotWindow, 0);
                            case '8 screens'
                                imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                imageTexture3 = Screen('MakeTexture', window(SCR3+4), app.img{1,IMG3});
                                Screen('DrawTexture', window(SCR3+4), imageTexture3, [], plotWindow, 0);
                                imageTexture4 = Screen('MakeTexture', window(SCR4+6), app.img{1,IMG4});
                                Screen('DrawTexture', window(SCR4+6), imageTexture4, [], plotWindow, 0);   
                        end

                        % Flip to the screen
                        switch app.screensDropDown.Value
                            case '2 screens'
                                vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                Screen('Flip', window(SCR1_rnd));
                            case '4 screens'
                                vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                Screen('Flip', window(SCR1_rnd));
                                Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                Screen('Flip', window(SCR2_rnd+2));
                            case '6 screens'
                                vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                Screen('Flip', window(SCR1_rnd));
                                Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                Screen('Flip', window(SCR2_rnd+2));
                                Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4)); 
                                Screen('Flip', window(SCR3_rnd+4));
                            case '8 screens'
                                vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                Screen('Flip', window(SCR1_rnd));
                                Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                Screen('Flip', window(SCR2_rnd+2));
                                Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4)); 
                                Screen('Flip', window(SCR3_rnd+4));
                                Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6)); 
                                Screen('Flip', window(SCR4_rnd+6));
                        end

                        % Increment the time
                        switch app.screensDropDown.Value
                            case '2 screens'
                                Screen('Close', imageTexture1);
                            case '4 screens'
                                Screen('Close', imageTexture1);
                                Screen('Close', imageTexture2);
                            case '6 screens'
                                Screen('Close', imageTexture1);
                                Screen('Close', imageTexture2);
                                Screen('Close', imageTexture3);
                            case '8 screens'
                                Screen('Close', imageTexture1);
                                Screen('Close', imageTexture2);
                                Screen('Close', imageTexture3);
                                Screen('Close', imageTexture4);
                        end

                    case 'Flickering'
                        if mod(display,2)==0
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    Screen('FillRect', window(SCR1), black(SCR1));
                                    Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd));
                                case '4 screens'
                                    Screen('FillRect', window(SCR1), black(SCR1));
                                    Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd));
                                    Screen('FillRect', window(SCR2+2), black(SCR2+2));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2));
                                case '6 screens'
                                    Screen('FillRect', window(SCR1), black(SCR1));
                                    Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd));
                                    Screen('FillRect', window(SCR2+2), black(SCR2+2));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2));
                                    Screen('FillRect', window(SCR3+4), black(SCR3+4));
                                    Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                    Screen('Flip', window(SCR3_rnd+4));
                                case '8 screens'
                                    Screen('FillRect', window(SCR1), black(SCR1));
                                    Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd));
                                    Screen('FillRect', window(SCR2+2), black(SCR2+2));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2));
                                    Screen('FillRect', window(SCR3+4), black(SCR3+4));
                                    Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                    Screen('Flip', window(SCR3_rnd+4));
                                    Screen('FillRect', window(SCR4+6), black(SCR4+6));
                                    Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6));
                                    Screen('Flip', window(SCR4_rnd+6));
                                    
                            end
                        else
                            % Draw the textures to the screen
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                    Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                case '4 screens'
                                    imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                    Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                    imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                    Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                case '6 screens'
                                    imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                    Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                    imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                    Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                    imageTexture3 = Screen('MakeTexture', window(SCR3+4), app.img{1,IMG3});
                                    Screen('DrawTexture', window(SCR3+4), imageTexture3, [], plotWindow, 0);
                                case '8 screens'
                                    imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{1,IMG1});
                                    Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow, 0);
                                    imageTexture2 = Screen('MakeTexture', window(SCR2+2), app.img{1,IMG2});
                                    Screen('DrawTexture', window(SCR2+2), imageTexture2, [], plotWindow, 0);   
                                    imageTexture3 = Screen('MakeTexture', window(SCR3+4), app.img{1,IMG3});
                                    Screen('DrawTexture', window(SCR3+4), imageTexture3, [], plotWindow, 0);
                                    imageTexture4 = Screen('MakeTexture', window(SCR4+6), app.img{1,IMG4});
                                    Screen('DrawTexture', window(SCR4+6), imageTexture4, [], plotWindow, 0); 
                            end

                            % Flip to the screen
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                    Screen('Flip', window(SCR1_rnd));
                                case '4 screens'
                                    vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                    Screen('Flip', window(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                    Screen('Flip', window(SCR2_rnd+2));
                                case '6 screens'
                                    vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                    Screen('Flip', window(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                    Screen('Flip', window(SCR2_rnd+2));
                                    Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4)); 
                                    Screen('Flip', window(SCR3_rnd+4));
                                case '8 screens'
                                    vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1)); 
                                    Screen('Flip', window(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2)); 
                                    Screen('Flip', window(SCR2_rnd+2));
                                    Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4)); 
                                    Screen('Flip', window(SCR3_rnd+4));
                                    Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6)); 
                                    Screen('Flip', window(SCR4_rnd+6));
                            end

                            % Increment the time
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    Screen('Close', imageTexture1);
                                case '4 screens'
                                    Screen('Close', imageTexture1);
                                    Screen('Close', imageTexture2);
                                case '6 screens'
                                    Screen('Close', imageTexture1);
                                    Screen('Close', imageTexture2);
                                    Screen('Close', imageTexture3);
                                case '8 screens'
                                    Screen('Close', imageTexture1);
                                    Screen('Close', imageTexture2);
                                    Screen('Close', imageTexture3);
                                    Screen('Close', imageTexture4);
                            end
                        end
                        countFlickTraining = countFlickTraining+1;
                        pause(1/app.freqTraining);
                end
                time = time + refreshTime(SCR1);
                display=display+1;
            end 
            switch app.screensDropDown.Value
                case '2 screens'
                    Screen('FillRect', window(SCR1), black(SCR1));
                    Screen('Flip', window(SCR1));
                    Screen('Flip', window(SCR1_rnd));
                case '4 screens'
                    Screen('FillRect', window(SCR1), black(SCR1));
                    Screen('Flip', window(SCR1));
                    Screen('Flip', window(SCR1_rnd));
                    Screen('FillRect', window(SCR2+2), black(SCR2+2));
                    Screen('Flip', window(SCR2+2));
                    Screen('Flip', window(SCR2_rnd+2));
                case '6 screens'
                    Screen('FillRect', window(SCR1), black(SCR1));
                    Screen('Flip', window(SCR1));
                    Screen('Flip', window(SCR1_rnd));
                    Screen('FillRect', window(SCR2+2), black(SCR2+2));
                    Screen('Flip', window(SCR2+2));
                    Screen('Flip', window(SCR2_rnd+2));
                    Screen('FillRect', window(SCR3+4), black(SCR3+4));
                    Screen('Flip', window(SCR3+4));
                    Screen('Flip', window(SCR3_rnd+4));
                case '8 screens'
                    Screen('FillRect', window(SCR1), black(SCR1));
                    Screen('Flip', window(SCR1));
                    Screen('Flip', window(SCR1_rnd));
                    Screen('FillRect', window(SCR2+2), black(SCR2+2));
                    Screen('Flip', window(SCR2+2));
                    Screen('Flip', window(SCR2_rnd+2));
                    Screen('FillRect', window(SCR3+4), black(SCR3+4));
                    Screen('Flip', window(SCR3+4));
                    Screen('Flip', window(SCR3_rnd+4));
                    Screen('FillRect', window(SCR4+6), black(SCR4+6));
                    Screen('Flip', window(SCR4+6));
                    Screen('Flip', window(SCR4_rnd+6));
            end

            pause(1)
        
            disp('TEST')
            switch app.screensDropDown.Value
                case '2 screens'
                    IMG1_1=app.r_imgTest{1,1}(1); %statistics
                    IMG2_1=app.r_imgTest2{1,1}(1); %noise
                case '4 screens'
                    IMG1_1=app.r_imgTest{1,1}(1); %statistics
                    IMG2_1=app.r_imgTest2{1,1}(1); %noise
                    IMG1_2=app.r_imgTest{2,1}(1);
                    IMG2_2=app.r_imgTest2{2,1}(1);
                case '6 screens'
                    IMG1_1=app.r_imgTest{1,1}(1); %statistics
                    IMG2_1=app.r_imgTest2{1,1}(1); %noise
                    IMG1_2=app.r_imgTest{2,1}(1);
                    IMG2_2=app.r_imgTest2{2,1}(1);
                    IMG1_3=app.r_imgTest{3,1}(1);
                    IMG2_3=app.r_imgTest2{3,1}(1);
                case '8 screens'
                    IMG1_1=app.r_imgTest{1,1}(1); %statistics
                    IMG2_1=app.r_imgTest2{1,1}(1); %noise
                    IMG1_2=app.r_imgTest{2,1}(1);
                    IMG2_2=app.r_imgTest2{2,1}(1);
                    IMG1_3=app.r_imgTest{3,1}(1);
                    IMG2_3=app.r_imgTest2{3,1}(1);
                    IMG1_4=app.r_imgTest{4,1}(1);
                    IMG2_4=app.r_imgTest2{4,1}(1);
            end

            switch app.screensDropDown.Value
                case '2 screens'
                    SCR1=app.r_screenTest{1,1}(1);
                    if SCR1==1
                        SCR1_rnd = 2;
                    else
                        SCR1_rnd = 1;
                    end
                case '4 screens'
                    SCR1=app.r_screenTest{1,1}(1);
                    if SCR1==1
                        SCR1_rnd = 2;
                    else
                        SCR1_rnd = 1;
                    end
                    SCR2=app.r_screenTest{2,1}(1);
                    if SCR2==1
                        SCR2_rnd = 2;
                    else
                        SCR2_rnd = 1;
                    end
                case '6 screens'
                    SCR1=app.r_screenTest{1,1}(1);
                    if SCR1==1
                        SCR1_rnd = 2;
                    else
                        SCR1_rnd = 1;
                    end
                    SCR2=app.r_screenTest{2,1}(1);
                    if SCR2==1
                        SCR2_rnd = 2;
                    else
                        SCR2_rnd = 1;
                    end
                    SCR3=app.r_screenTest{3,1}(1);
                    if SCR3==1
                        SCR3_rnd = 2;
                    else
                        SCR3_rnd = 1;
                    end
                case '8 screens'
                    SCR1=app.r_screenTest{1,1}(1);
                    if SCR1==1
                        SCR1_rnd = 2;
                    else
                        SCR1_rnd = 1;
                    end
                    SCR2=app.r_screenTest{2,1}(1);
                    if SCR2==1
                        SCR2_rnd = 2;
                    else
                        SCR2_rnd = 1;
                    end
                    SCR3=app.r_screenTest{3,1}(1);
                    if SCR3==1
                        SCR3_rnd = 2;
                    else
                        SCR3_rnd = 1;
                    end
                    SCR4=app.r_screenTest{4,1}(1);
                    if SCR4==1
                        SCR4_rnd = 2;
                    else
                        SCR4_rnd = 1;
                    end
            end

            vblTime = Screen('Flip', window(1));
            switch app.screensDropDown.Value
                case '2 screens'
                    screens=2;
                case '4 screens'
                    screens=4;
                case '6 screens'
                    screens=6;
                case '8 screens'
                    screens=8;
            end
            for j=2:screens
                  Screen('Flip', window(j));
            end
            
            % Maximum priority level
            topPriorityLevel = MaxPriority(window(SCR1));
            Priority(topPriorityLevel);
            % Our square will oscilate with a sine wave function to the left and right of the screen. These are the parameters for the sine wave
            amplitude1 = screenXpixels(SCR1) * app.OscillationamplitudeEditField.Value; % horizontal oscillation distance
            amplitude2 = screenXpixels(SCR1_rnd) * app.OscillationamplitudeEditField.Value;
            angFreq = 2 * pi * app.freqTest;
            startPhase = 0;
            time = 0;
            
            display=1;
            t0=vblTime;
            counterTest=0;
            countFlickTest = 0;
            sqpos1=xCenter(SCR1);
            sqpos2=xCenter(SCR1);
            sqpos3=xCenter(SCR1);
            maxAmplTest = 0;
            while vblTime<t0+30
                % Position of the square on this frame
                xpos1 = amplitude1 * sin(angFreq * time + startPhase);
                xpos2 = -amplitude2 * sin(angFreq * time + startPhase);     
                % Add this position to the screen center coordinate. This is the point we want our square to oscillate around
                switch app.Switch.Value
                    case 'Translation'
                        squareXpos1 = xCenter(SCR1) + xpos1;
                        squareXpos2 = xCenter(SCR1_rnd) + xpos2;
                    case 'Flickering'
                        squareXpos1 = xCenter(SCR1);
                        squareXpos2 = xCenter(SCR1_rnd);
                end
                sqpos1=sqpos2;
                sqpos2=sqpos3;
                sqpos3=squareXpos1;
                if ((sqpos2>sqpos1) && (sqpos3<sqpos2)) || ((sqpos2<sqpos1) && (sqpos3>sqpos2))
                    if sqpos3 > maxAmplTest
                        maxAmplTest = sqpos3;
                    end
                    counterTest=counterTest+1;
                end
                % Set y position
                baseRect1 = [0,0,app.imgSize{2}(2),app.imgSize{2}(1)];
                baseRect2 = [0,0,app.imgSize{3}(2),app.imgSize{3}(1)];
                plotWindow1 = CenterRectOnPointd(baseRect1, squareXpos1, yCenter(SCR1)+app.ImageshiftEditField.Value);
                plotWindow2 = CenterRectOnPointd(baseRect2, squareXpos2, yCenter(SCR1_rnd)+app.ImageshiftEditField.Value);
                switch app.Switch.Value
                    case 'Translation'
                        switch app.screensDropDown.Value
                            case '2 screens'
                                imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                            case '4 screens'
                                imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                            case '6 screens'
                                imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                imageTexture5 = Screen('MakeTexture', window(SCR3+4), app.img{2,IMG1_3});
                                imageTexture6 = Screen('MakeTexture', window(SCR3_rnd+4), app.img{3,IMG2_3});
                                Screen('DrawTexture', window(SCR3+4), imageTexture5, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR3_rnd+4), imageTexture6, [], plotWindow2, 0); 
                            case '8 screens'
                                imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                imageTexture5 = Screen('MakeTexture', window(SCR3+4), app.img{2,IMG1_3});
                                imageTexture6 = Screen('MakeTexture', window(SCR3_rnd+4), app.img{3,IMG2_3});
                                Screen('DrawTexture', window(SCR3+4), imageTexture5, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR3_rnd+4), imageTexture6, [], plotWindow2, 0); 
                                imageTexture7 = Screen('MakeTexture', window(SCR4+6), app.img{2,IMG1_4});
                                imageTexture8 = Screen('MakeTexture', window(SCR4_rnd+6), app.img{3,IMG2_4});
                                Screen('DrawTexture', window(SCR4+6), imageTexture7, [], plotWindow1, 0); 
                                Screen('DrawTexture', window(SCR4_rnd+6), imageTexture8, [], plotWindow2, 0); 
                        end

                        % Flip to the screen
                        switch app.screensDropDown.Value
                            case '2 screens'
                                vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                            case '4 screens'
                                vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                            case '6 screens'
                                vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                            case '8 screens'
                                vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6));
                                Screen('Flip', window(SCR4_rnd+6), vblTime + (waitFrames(SCR4_rnd+6) - 0.5) * refreshTime(SCR4_rnd+6));
                        end
                        
                        switch app.screensDropDown.Value
                            case '2 screens'
                                Screen('Close', imageTexture1);
                                Screen('Close', imageTexture2);
                            case '4 screens'
                                Screen('Close', imageTexture1);
                                Screen('Close', imageTexture2);
                                Screen('Close', imageTexture3);
                                Screen('Close', imageTexture4);
                            case '6 screens'
                                Screen('Close', imageTexture1);
                                Screen('Close', imageTexture2);
                                Screen('Close', imageTexture3);
                                Screen('Close', imageTexture4);
                                Screen('Close', imageTexture5);
                                Screen('Close', imageTexture6);
                            case '8 screens'
                                Screen('Close', imageTexture1);
                                Screen('Close', imageTexture2);
                                Screen('Close', imageTexture3);
                                Screen('Close', imageTexture4);
                                Screen('Close', imageTexture5);
                                Screen('Close', imageTexture6);
                                Screen('Close', imageTexture7);
                                Screen('Close', imageTexture8);
                        end

                    case 'Flickering'
                        if mod(display,2)==0
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    Screen('FillRect', window(1), black(1));
                                    Screen('FillRect', window(2), black(2));
                                case '4 screens'
                                    Screen('FillRect', window(1), black(1));
                                    Screen('FillRect', window(2), black(2));
                                    Screen('FillRect', window(3), black(3));
                                    Screen('FillRect', window(4), black(4));
                                case '6 screens'
                                    Screen('FillRect', window(1), black(1));
                                    Screen('FillRect', window(2), black(2));
                                    Screen('FillRect', window(3), black(3));
                                    Screen('FillRect', window(4), black(4));
                                    Screen('FillRect', window(5), black(5));
                                    Screen('FillRect', window(6), black(6));
                                case '8 screens'
                                    Screen('FillRect', window(1), black(1));
                                    Screen('FillRect', window(2), black(2));
                                    Screen('FillRect', window(3), black(3));
                                    Screen('FillRect', window(4), black(4));
                                    Screen('FillRect', window(5), black(5));
                                    Screen('FillRect', window(6), black(6));
                                    Screen('FillRect', window(7), black(7));
                                    Screen('FillRect', window(8), black(8));
                            end
                            
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                case '4 screens'
                                    Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                case '6 screens'
                                    Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                    Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                    Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                case '8 screens'
                                    Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                    Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                    Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                    Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6));
                                    Screen('Flip', window(SCR4_rnd+6), vblTime + (waitFrames(SCR4_rnd+6) - 0.5) * refreshTime(SCR4_rnd+6));
                            end
                        else
                            % Draw the textures to the screen
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                    imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                    Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                case '4 screens'
                                    imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                    imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                    Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                    imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                    imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                    Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                case '6 screens'
                                    imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                    imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                    Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                    imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                    imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                    Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                    imageTexture5 = Screen('MakeTexture', window(SCR3+4), app.img{2,IMG1_3});
                                    imageTexture6 = Screen('MakeTexture', window(SCR3_rnd+4), app.img{3,IMG2_3});
                                    Screen('DrawTexture', window(SCR3+4), imageTexture5, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR3_rnd+4), imageTexture6, [], plotWindow2, 0); 
                                case '8 screens'
                                    imageTexture1 = Screen('MakeTexture', window(SCR1), app.img{2,IMG1_1}); %statistics
                                    imageTexture2 = Screen('MakeTexture', window(SCR1_rnd), app.img{3,IMG2_1}); %noise
                                    Screen('DrawTexture', window(SCR1), imageTexture1, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR1_rnd), imageTexture2, [], plotWindow2, 0);
                                    imageTexture3 = Screen('MakeTexture', window(SCR2+2), app.img{2,IMG1_2});
                                    imageTexture4 = Screen('MakeTexture', window(SCR2_rnd+2), app.img{3,IMG2_2});
                                    Screen('DrawTexture', window(SCR2+2), imageTexture3, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR2_rnd+2), imageTexture4, [], plotWindow2, 0); 
                                    imageTexture5 = Screen('MakeTexture', window(SCR3+4), app.img{2,IMG1_3});
                                    imageTexture6 = Screen('MakeTexture', window(SCR3_rnd+4), app.img{3,IMG2_3});
                                    Screen('DrawTexture', window(SCR3+4), imageTexture5, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR3_rnd+4), imageTexture6, [], plotWindow2, 0); 
                                    imageTexture7 = Screen('MakeTexture', window(SCR4+6), app.img{2,IMG1_4});
                                    imageTexture8 = Screen('MakeTexture', window(SCR4_rnd+6), app.img{3,IMG2_4});
                                    Screen('DrawTexture', window(SCR4+6), imageTexture7, [], plotWindow1, 0); 
                                    Screen('DrawTexture', window(SCR4_rnd+6), imageTexture8, [], plotWindow2, 0); 
                            end
                            % Flip to the screen
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                case '4 screens'
                                    vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                case '6 screens'
                                    vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                    Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                    Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                case '8 screens'
                                    vblTime  = Screen('Flip', window(SCR1), vblTime + (waitFrames(SCR1) - 0.5) * refreshTime(SCR1));
                                    Screen('Flip', window(SCR1_rnd), vblTime + (waitFrames(SCR1_rnd) - 0.5) * refreshTime(SCR1_rnd));
                                    Screen('Flip', window(SCR2+2), vblTime + (waitFrames(SCR2+2) - 0.5) * refreshTime(SCR2+2));
                                    Screen('Flip', window(SCR2_rnd+2), vblTime + (waitFrames(SCR2_rnd+2) - 0.5) * refreshTime(SCR2_rnd+2));
                                    Screen('Flip', window(SCR3+4), vblTime + (waitFrames(SCR3+4) - 0.5) * refreshTime(SCR3+4));
                                    Screen('Flip', window(SCR3_rnd+4), vblTime + (waitFrames(SCR3_rnd+4) - 0.5) * refreshTime(SCR3_rnd+4));
                                    Screen('Flip', window(SCR4+6), vblTime + (waitFrames(SCR4+6) - 0.5) * refreshTime(SCR4+6));
                                    Screen('Flip', window(SCR4_rnd+6), vblTime + (waitFrames(SCR4_rnd+6) - 0.5) * refreshTime(SCR4_rnd+6));
                            end
                            
                            switch app.screensDropDown.Value
                                case '2 screens'
                                    Screen('Close', imageTexture1);
                                    Screen('Close', imageTexture2);
                                case '4 screens'
                                    Screen('Close', imageTexture1);
                                    Screen('Close', imageTexture2);
                                    Screen('Close', imageTexture3);
                                    Screen('Close', imageTexture4);
                                case '6 screens'
                                    Screen('Close', imageTexture1);
                                    Screen('Close', imageTexture2);
                                    Screen('Close', imageTexture3);
                                    Screen('Close', imageTexture4);
                                    Screen('Close', imageTexture5);
                                    Screen('Close', imageTexture6);
                                case '8 screens'
                                    Screen('Close', imageTexture1);
                                    Screen('Close', imageTexture2);
                                    Screen('Close', imageTexture3);
                                    Screen('Close', imageTexture4);
                                    Screen('Close', imageTexture5);
                                    Screen('Close', imageTexture6);
                                    Screen('Close', imageTexture7);
                                    Screen('Close', imageTexture8);
                            end
                        end
                        countFlickTest = countFlickTest+1;
                        pause(1/app.freqTest);
                end
                % Increment the time
                time = time + refreshTime(SCR1);
                display=display+1;
            end
            switch app.screensDropDown.Value
                case '2 screens'
                    Screen('FillRect', window(1), black(1));
                    Screen('FillRect', window(2), black(2));
                case '4 screens'
                    Screen('FillRect', window(1), black(1));
                    Screen('FillRect', window(2), black(2));
                    Screen('FillRect', window(3), black(3));
                    Screen('FillRect', window(4), black(4));
                case '6 screens'
                    Screen('FillRect', window(1), black(1));
                    Screen('FillRect', window(2), black(2));
                    Screen('FillRect', window(3), black(3));
                    Screen('FillRect', window(4), black(4));
                    Screen('FillRect', window(5), black(5));
                    Screen('FillRect', window(6), black(6));
                case '8 screens'
                    Screen('FillRect', window(1), black(1));
                    Screen('FillRect', window(2), black(2));
                    Screen('FillRect', window(3), black(3));
                    Screen('FillRect', window(4), black(4));
                    Screen('FillRect', window(5), black(5));
                    Screen('FillRect', window(6), black(6));
                    Screen('FillRect', window(7), black(7));
                    Screen('FillRect', window(8), black(8));
            end

            switch app.screensDropDown.Value
                case '2 screens'
                    Screen('Flip', window(1));
                    Screen('Flip', window(2));
                case '4 screens'
                    Screen('Flip', window(1));
                    Screen('Flip', window(2));
                    Screen('Flip', window(3));
                    Screen('Flip', window(4));
                case '6 screens'
                    Screen('Flip', window(1));
                    Screen('Flip', window(2));
                    Screen('Flip', window(3));
                    Screen('Flip', window(4));
                    Screen('Flip', window(5));
                    Screen('Flip', window(6));
                case '8 screens'
                    Screen('Flip', window(1));
                    Screen('Flip', window(2));
                    Screen('Flip', window(3));
                    Screen('Flip', window(4));
                    Screen('Flip', window(5));
                    Screen('Flip', window(6));
                    Screen('Flip', window(7));
                    Screen('Flip', window(8));
            end
            
            sca;
            
            px2mm_X = width_mm_screen(1)/screenXpixels(1);
            %disp(['Dimentions: ' num2str(screenYpixels(1)) 'px = ' num2str(height_mm_screen(1)) 'mm'])
            %disp(['Dimentions: ' num2str(screenXpixels(1)) 'px = ' num2str(width_mm_screen(1)) 'mm'])
            switch app.Switch.Value
                case 'Translation'
                    travelledPixelsImprinting = (maxAmplTrain-xCenter(SCR1))*2*counterImprinting-(maxAmplTrain-xCenter(SCR1))+(maxAmplTrain-squareXpos3);
                    travelledPixelsTest = (maxAmplTest-xCenter(SCR1))*2*counterTest-(maxAmplTest-xCenter(SCR1))+(maxAmplTest-sqpos3);
                    speedImprinting = travelledPixelsImprinting/30; %px/s
                    speedTest = travelledPixelsTest/30; %px/s
                    adjTestSpeedFactor = speedImprinting/speedTest;
                    app.freqTest = app.freqTest*adjTestSpeedFactor;
                    disp(['Speed training: ' num2str(speedImprinting*px2mm_X) ' mm/s'])
                    disp(['Speed test: ' num2str(speedTest*px2mm_X) ' mm/s'])
                    disp(['Speed factor: ' num2str(speedImprinting/speedTest)])
                case 'Flickering'
                    speedImprinting = countFlickTraining/30; %flick/s
                    speedTest = countFlickTest/30; %flick/s
                    adjTestSpeedFactor = speedImprinting/speedTest;
                    app.freqTest = app.freqTest*adjTestSpeedFactor;
                    disp(['Speed training: ' num2str(speedImprinting) ' flick/s'])
                    disp(['Speed test: ' num2str(speedTest) ' flick/s'])
                    disp(['Speed factor: ' num2str(speedImprinting/speedTest)])
            end
            
            app.ChecklatenciesButton.BackgroundColor = [0, 1, 0];
            app.AdjustforPClatenciesCheckBox.Value=1;
            
            disp(['Frequency test will be: ' num2str(app.freqTest) ' to adequate speed'])
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.freqTraining = app.FrequencyEditField.Value;
            app.freqTest = app.FrequencyEditField.Value;
            app.ChecklatenciesButton.BackgroundColor = [1.00,0.41,0.16];
            app.AdjustforPClatenciesCheckBox.Value=0;
        end

        % Value changed function: AdjustforPClatenciesCheckBox
        function AdjustforPClatenciesCheckBoxValueChanged(app, event)
            value = app.AdjustforPClatenciesCheckBox.Value;
            if value == 0
                app.freqTraining = app.FrequencyEditField.Value;
                app.freqTest = app.FrequencyEditField.Value;
                app.ChecklatenciesButton.BackgroundColor = [1.00,0.41,0.16];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Selecttest1folderButton = uibutton(app.UIFigure, 'push');
            app.Selecttest1folderButton.ButtonPushedFcn = createCallbackFcn(app, @Selecttest1folderButtonPushed, true);
            app.Selecttest1folderButton.Text = 'Select test 1 folder';
            app.Selecttest2folderButton = uibutton(app.UIFigure, 'push');
            app.Selecttest2folderButton.ButtonPushedFcn = createCallbackFcn(app, @Selecttest2folderButtonPushed, true);
            app.Selecttest2folderButton.Text = 'Select test 2 folder';
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.Limits = [1 50];
            app.Slider1.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.Slider1.ValueChangingFcn = createCallbackFcn(app, @Slider1ValueChanging, true);
            app.Slider1.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.Slider1.Value = 1;
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [1 50];
            app.Slider2.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider2.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.Slider2.Value = 1;
            app.Imagetest1dimLabel = uilabel(app.UIFigure);
            app.Imagetest1dimLabel.Text = 'Image test 1 dim:';
            app.Imagetest2dimLabel = uilabel(app.UIFigure);
            app.Imagetest2dimLabel.Text = 'Image test 2 dim:';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ImprintingPanel = uipanel(app.UIFigure);
            app.ImprintingPanel.Title = 'Imprinting';
            app.SessionlengthminEditFieldLabel = uilabel(app.ImprintingPanel);
            app.SessionlengthminEditFieldLabel.Text = 'Session length (min)';
            app.SessionlengthminEditField = uieditfield(app.ImprintingPanel, 'numeric');
            app.SessionlengthminEditField.ValueChangedFcn = createCallbackFcn(app, @SessionlengthminEditFieldValueChanged, true);
            app.SessionlengthminEditField.Value = 120;
            app.IntersessionspauseminEditFieldLabel = uilabel(app.ImprintingPanel);
            app.IntersessionspauseminEditFieldLabel.Text = 'Inter sessions pause (min)';
            app.IntersessionspauseminEditField = uieditfield(app.ImprintingPanel, 'numeric');
            app.IntersessionspauseminEditField.ValueChangedFcn = createCallbackFcn(app, @IntersessionspauseminEditFieldValueChanged, true);
            app.SessionrepetitionsEditFieldLabel = uilabel(app.ImprintingPanel);
            app.SessionrepetitionsEditFieldLabel.Text = 'Session repetitions';
            app.SessionrepetitionsEditField = uieditfield(app.ImprintingPanel, 'numeric');
            app.SessionrepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @SessionrepetitionsEditFieldValueChanged, true);
            app.SessionrepetitionsEditField.Value = 7;
            app.RestingtimeminEditFieldLabel = uilabel(app.ImprintingPanel);
            app.RestingtimeminEditFieldLabel.Text = 'Resting time (min)';
            app.RestingtimeminEditField = uieditfield(app.ImprintingPanel, 'numeric');
            app.RestingtimeminEditField.ValueChangedFcn = createCallbackFcn(app, @RestingtimeminEditFieldValueChanged, true);
            app.RestingtimeminEditField.Value = 600;
            app.NumberofdaysEditFieldLabel = uilabel(app.ImprintingPanel);
            app.NumberofdaysEditFieldLabel.Text = 'Number of days';
            app.NumberofdaysEditField = uieditfield(app.ImprintingPanel, 'numeric');
            app.NumberofdaysEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofdaysEditFieldValueChanged, true);
            app.NumberofdaysEditField.Value = 1;
            app.TestPanel = uipanel(app.UIFigure);
            app.TestPanel.Title = 'Test';
            app.SessionlengthminEditField_2Label = uilabel(app.TestPanel);
            app.SessionlengthminEditField_2Label.Text = 'Session length (min)';
            app.SessionlengthminEditField_2 = uieditfield(app.TestPanel, 'numeric');
            app.SessionlengthminEditField_2.ValueChangedFcn = createCallbackFcn(app, @SessionlengthminEditField_2ValueChanged, true);
            app.SessionlengthminEditField_2.Value = 60;
            app.IntersessionspauseminEditField_2Label = uilabel(app.TestPanel);
            app.IntersessionspauseminEditField_2Label.Text = 'Inter sessions pause (min)';
            app.IntersessionspauseminEditField_2 = uieditfield(app.TestPanel, 'numeric');
            app.IntersessionspauseminEditField_2.ValueChangedFcn = createCallbackFcn(app, @IntersessionspauseminEditField_2ValueChanged, true);
            app.SessionrepetitionsEditField_2Label = uilabel(app.TestPanel);
            app.SessionrepetitionsEditField_2Label.Text = 'Session repetitions';
            app.SessionrepetitionsEditField_2 = uieditfield(app.TestPanel, 'numeric');
            app.SessionrepetitionsEditField_2.ValueChangedFcn = createCallbackFcn(app, @SessionrepetitionsEditField_2ValueChanged, true);
            app.SessionrepetitionsEditField_2.Value = 1;
            app.RestingtimeminEditField_2Label = uilabel(app.TestPanel);
            app.RestingtimeminEditField_2Label.Text = 'Resting time (min)';
            app.RestingtimeminEditField_2 = uieditfield(app.TestPanel, 'numeric');
            app.RestingtimeminEditField_2.ValueChangedFcn = createCallbackFcn(app, @RestingtimeminEditField_2ValueChanged, true);
            app.NumberofdaysEditField_2Label = uilabel(app.TestPanel);
            app.NumberofdaysEditField_2Label.Text = 'Number of days';
            app.NumberofdaysEditField_2 = uieditfield(app.TestPanel, 'numeric');
            app.NumberofdaysEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofdaysEditField_2ValueChanged, true);
            app.NumberofdaysEditField_2.Value = 1;
            app.TotaldurationLabel = uilabel(app.UIFigure);
            app.TotaldurationLabel.Text = 'Total duration:';
            app.EndtimeLabel = uilabel(app.UIFigure);
            app.EndtimeLabel.Text = 'End time:';
            app.ScreencheckButton = uibutton(app.UIFigure, 'push');
            app.ScreencheckButton.ButtonPushedFcn = createCallbackFcn(app, @ScreencheckButtonPushed, true);
            app.ScreencheckButton.Text = 'Screen # check';
            app.StartingtimeddMonyyyyhhmmssLabel = uilabel(app.UIFigure);
            app.StartingtimeddMonyyyyhhmmssLabel.Text = 'Starting time [dd-Mon-yyyy hh:mm:ss]';
            app.StartingtimeEditField = uieditfield(app.UIFigure, 'text');
            app.StartingtimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartingtimeEditFieldValueChanged, true);
            app.StartingtimeEditField.Value = '00-000-0000 00:00:00';
            app.SaveinfostoEditFieldLabel = uilabel(app.UIFigure);
            app.SaveinfostoEditFieldLabel.Text = 'Save infos to:';
            app.SaveinfostoEditField = uieditfield(app.UIFigure, 'text');
            app.SaveinfostoEditField.Value = 'chick';
            app.SkipsynctestCheckBox = uicheckbox(app.UIFigure);
            app.SkipsynctestCheckBox.Text = 'Skip sync test';
            app.File1Label = uilabel(app.UIFigure);
            app.File1Label.Text = 'File 1:';
            app.File2Label = uilabel(app.UIFigure);
            app.File2Label.Text = 'File 2:';
            app.MovementparametersPanel = uipanel(app.UIFigure);
            app.MovementparametersPanel.Title = 'Movement parameters';
            app.ImageframeratefpsEditFieldLabel = uilabel(app.MovementparametersPanel);
            app.ImageframeratefpsEditFieldLabel.Text = 'Image frame rate (fps)';
            app.ImageframeratefpsEditField = uieditfield(app.MovementparametersPanel, 'numeric');
            app.ImageframeratefpsEditField.ValueChangedFcn = createCallbackFcn(app, @ImageframeratefpsEditFieldValueChanged, true);
            app.ImageframeratefpsEditField.Value = 120;
            app.ImageshiftEditFieldLabel = uilabel(app.MovementparametersPanel);
            app.ImageshiftEditFieldLabel.Text = 'Image shift';
            app.ImageshiftEditField = uieditfield(app.MovementparametersPanel, 'numeric');
            app.ImageshiftEditField.ValueChangedFcn = createCallbackFcn(app, @ImageshiftEditFieldValueChanged, true);
            app.ImageshiftEditField.Value = 250;
            app.OscillationamplitudeEditFieldLabel = uilabel(app.MovementparametersPanel);
            app.OscillationamplitudeEditFieldLabel.Text = 'Oscillation amplitude';
            app.OscillationamplitudeEditField = uieditfield(app.MovementparametersPanel, 'numeric');
            app.OscillationamplitudeEditField.Value = 0.32;
            app.FrequencyEditFieldLabel = uilabel(app.MovementparametersPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.MovementparametersPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 0.11;
            app.Switch = uiswitch(app.MovementparametersPanel, 'slider');
            app.Switch.Items = {'Translation', 'Flickering'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Translation';
            app.SelectimprintingfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectimprintingfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimprintingfolderButtonPushed, true);
            app.SelectimprintingfolderButton.Text = 'Select imprinting folder';
            app.Slider1_2 = uislider(app.UIFigure);
            app.Slider1_2.Limits = [1 50];
            app.Slider1_2.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.Slider1_2.ValueChangingFcn = createCallbackFcn(app, @Slider1_2ValueChanging, true);
            app.Slider1_2.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.Slider1_2.Value = 1;
            app.ImageimprintingdimLabel = uilabel(app.UIFigure);
            app.ImageimprintingdimLabel.Text = 'Image imprinting dim:';
            app.FileimprintingLabel = uilabel(app.UIFigure);
            app.FileimprintingLabel.Text = 'File imprinting:';
            app.Setin4minButton = uibutton(app.UIFigure, 'push');
            app.Setin4minButton.ButtonPushedFcn = createCallbackFcn(app, @Setin4minButtonPushed, true);
            app.Setin4minButton.Text = 'Set in 4 min';
            app.SetnowButton = uibutton(app.UIFigure, 'push');
            app.SetnowButton.ButtonPushedFcn = createCallbackFcn(app, @SetnowButtonPushed, true);
            app.SetnowButton.Text = 'Set now';
            app.Setin1minButton = uibutton(app.UIFigure, 'push');
            app.Setin1minButton.ButtonPushedFcn = createCallbackFcn(app, @Setin1minButtonPushed, true);
            app.Setin1minButton.Text = 'Set in 1min';
            app.Setin2minButton = uibutton(app.UIFigure, 'push');
            app.Setin2minButton.ButtonPushedFcn = createCallbackFcn(app, @Setin2minButtonPushed, true);
            app.Setin2minButton.Text = 'Set in 2min';
            app.Setin3minButton = uibutton(app.UIFigure, 'push');
            app.Setin3minButton.ButtonPushedFcn = createCallbackFcn(app, @Setin3minButtonPushed, true);
            app.Setin3minButton.Text = 'Set in 3min';
            app.ChecklatenciesButton = uibutton(app.UIFigure, 'push');
            app.ChecklatenciesButton.ButtonPushedFcn = createCallbackFcn(app, @ChecklatenciesButtonPushed, true);
            app.ChecklatenciesButton.Text = 'Check latencies';
            app.AdjustforPClatenciesCheckBox = uicheckbox(app.UIFigure);
            app.AdjustforPClatenciesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdjustforPClatenciesCheckBoxValueChanged, true);
            app.AdjustforPClatenciesCheckBox.Text = 'Adjust for PC latencies';
            app.screensDropDownLabel = uilabel(app.UIFigure);
            app.screensDropDownLabel.Text = '# screens';
            app.screensDropDown = uidropdown(app.UIFigure);
            app.screensDropDown.Items = {'2 screens', '4 screens', '6 screens', '8 screens'};
            app.screensDropDown.Value = '2 screens';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Image test 1')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Image test 2')
            app.UIAxes1_2 = uiaxes(app.UIFigure);
            title(app.UIAxes1_2, 'Image imprinting')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImprintSchedule_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end